classdef RobotKinematics_v0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlanarHopperVisualizerUIFigure  matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        TabGroup                        matlab.ui.container.TabGroup
        ForwardKinematicsTab            matlab.ui.container.Tab
        Joint1AngleKnobLabel            matlab.ui.control.Label
        Joint1AngleKnob                 matlab.ui.control.Knob
        Joint2AngleKnobLabel            matlab.ui.control.Label
        Joint2AngleKnob                 matlab.ui.control.Knob
        EditField                       matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        Joint3AngleKnobLabel            matlab.ui.control.Label
        Joint3AngleKnob                 matlab.ui.control.Knob
        EditField_3                     matlab.ui.control.NumericEditField
        InverseKinematicsTab            matlab.ui.container.Tab
        FootXPosKnobLabel               matlab.ui.control.Label
        FootXPosKnob                    matlab.ui.control.Knob
        FootYPosKnobLabel               matlab.ui.control.Label
        FootYPosKnob                    matlab.ui.control.Knob
        FootAngleKnobLabel              matlab.ui.control.Label
        FootAngleKnob                   matlab.ui.control.Knob
        EditField_4                     matlab.ui.control.NumericEditField
        EditField_5                     matlab.ui.control.NumericEditField
        EditField_6                     matlab.ui.control.NumericEditField
        ForceTorqueTab                  matlab.ui.container.Tab
        SwitchLabel                     matlab.ui.control.Label
        Switch                          matlab.ui.control.ToggleSwitch
        FxEditFieldLabel                matlab.ui.control.Label
        FxEditField                     matlab.ui.control.NumericEditField
        FyEditFieldLabel                matlab.ui.control.Label
        FyEditField                     matlab.ui.control.NumericEditField
        TzEditFieldLabel                matlab.ui.control.Label
        TzEditField                     matlab.ui.control.NumericEditField
        T1EditFieldLabel                matlab.ui.control.Label
        T1EditField                     matlab.ui.control.NumericEditField
        T2EditFieldLabel                matlab.ui.control.Label
        T2EditField                     matlab.ui.control.NumericEditField
        T3EditFieldLabel                matlab.ui.control.Label
        T3EditField                     matlab.ui.control.NumericEditField
        ConfigurationStatusPanel        matlab.ui.container.Panel
        SolvableandsatisfiesjointlimitsLampLabel  matlab.ui.control.Label
        SolvableandsatisfiesjointlimitsLamp  matlab.ui.control.Lamp
        SolvablebutlinkscollideLampLabel  matlab.ui.control.Label
        SolvablebutlinkscollideLamp     matlab.ui.control.Lamp
        SolvablebutviolatesjointlimitsLampLabel  matlab.ui.control.Label
        SolvablebutviolatesjointlimitsLamp  matlab.ui.control.Lamp
        UnsolvableLampLabel             matlab.ui.control.Label
        UnsolvableLamp                  matlab.ui.control.Lamp
        EditLinkLengthsPanel            matlab.ui.container.Panel
        Link1SliderLabel                matlab.ui.control.Label
        Link1Slider                     matlab.ui.control.Slider
        Link2SliderLabel                matlab.ui.control.Label
        Link2Slider                     matlab.ui.control.Slider
        Link3SliderLabel                matlab.ui.control.Label
        Link3Slider                     matlab.ui.control.Slider
        Link4SliderLabel                matlab.ui.control.Label
        Link4Slider                     matlab.ui.control.Slider
        Link5SliderLabel                matlab.ui.control.Label
        Link5Slider                     matlab.ui.control.Slider
        Link6SliderLabel                matlab.ui.control.Label
        Link6Slider                     matlab.ui.control.Slider
        Link7SliderLabel                matlab.ui.control.Label
        Link7Slider                     matlab.ui.control.Slider
        Link8SliderLabel                matlab.ui.control.Label
        Link8Slider                     matlab.ui.control.Slider
        LR_MxSliderLabel                matlab.ui.control.Label
        LR_MxSlider                     matlab.ui.control.Slider
        LR_MySliderLabel                matlab.ui.control.Label
        LR_MySlider                     matlab.ui.control.Slider
        EditField_7                     matlab.ui.control.NumericEditField
        EditField_8                     matlab.ui.control.NumericEditField
        EditField_9                     matlab.ui.control.NumericEditField
        EditField_10                    matlab.ui.control.NumericEditField
        EditField_11                    matlab.ui.control.NumericEditField
        EditField_12                    matlab.ui.control.NumericEditField
        EditField_13                    matlab.ui.control.NumericEditField
        EditField_14                    matlab.ui.control.NumericEditField
        EditField_15                    matlab.ui.control.NumericEditField
        EditField_16                    matlab.ui.control.NumericEditField
        HelpButton                      matlab.ui.control.Button
        AboutButton                     matlab.ui.control.Button
        SENDITButton                    matlab.ui.control.Button
        ResetLengthsButton              matlab.ui.control.Button
        ResetConfigButton               matlab.ui.control.Button
    end


    properties (Access = public)        
        defaultLengths = [0.053; 0.139; 0.097; 0.0983; 0.053; 0.139; 0.0692; 0.1018; 0.0573; 0.0573; 0.0082; 0.0082]; % link lengths % Description
        
        lengths = zeros(12,1);
        
        defaultFootPose = [0; -0.22; -pi/2];
        defaultFootPoseDeg = [0; -0.22; -90];
        footPoseDeg = zeros(3,1);
        footPose = zeros(3,1);
        
        angles = zeros(3,3);
        
        defaultWrench = zeros(3,1);
        wrench = zeros(3,1);
        
        defaultTorques = zeros(3,1);
        torques = zeros(3,1);
    end

    methods (Access = public)
    
        function [angles] = subchainIK(app,footPose,lengths,plotOption)
            %% Description:
            %   Computes inverse kinematics for each sub-chain (theta, phi, and psi
            %   chains)
            %
            % Inputs:
            %   footPose: a 3x1 column vector representing the foot pose:
            %       footPose = [xF;
            %                   yF;
            %                   angF];
            %   lengths: the relevant dimensions of the robot, stored in a 10x1 column
            %       vector according to this order:
            %       lengths = [L1; L2; L3; L4; L5 L6; L7; L8; B1x; B2x], where
            %       B1x, B1y, L1, L2, and L8 correspond to the theta-chain,
            %       L3, L4, L7, and L8 correspond to the phi-chain, and
            %       B2x, B2y, L5, L6, and L8 correspond to the psi-chain.
            %
            % Outputs:
            %   angles = [theta1    theta2  theta3;
            %             phi1      phi2    phi3;
            %             psi1      psi2    psi3];
            %
            % Dependencies:
            %   none
            
            %% "unpack" link lengths from "lengths" input vector:
            % Note: this step is just for cleaner-looking code.
            L1 = lengths(1);
            L2 = lengths(2);
            L3 = lengths(3);
            L4 = lengths(4);
            L5 = lengths(5);
            L6 = lengths(6);
            L7 = lengths(7);
            L8 = lengths(8);
            B1x = lengths(9);
            B2x = lengths(10);
            B1y = lengths(11);
            B2y = lengths(12);
            
            %% Extract foot pose:
            xF = footPose(1);
            yF = footPose(2);
            angF = footPose(3);
            
            % Calculate (x,y) location of "lower ankle" joint, from foot pose:
            xA = xF - L8*cos(angF);
            yA = yF - L8*sin(angF);
            
            % Calculate (x,y) location of "upper ankle" joint, from foot pose:
            xAu = xF - (L7+L8)*cos(angF);
            yAu = yF - (L7+L8)*sin(angF);
            
            %% IK for theta-chain:
            % Calculate (x,y) location of "hip" joint:
            xHtheta = -B1x;
            yHtheta = B1y;
            
            % Calculate hip angle:
            xAptheta = -xHtheta + xA;
            yAptheta = yHtheta - yA;
            gammatheta = wrapToPi(abs(atan2(yAptheta,xAptheta)));
            alphatheta = wrapToPi(acos((xAptheta^2 + yAptheta^2 + L1^2 - L2^2)/(2*L1*sqrt(xAptheta^2 + yAptheta^2))));
            theta1 = wrapToPi(-gammatheta - alphatheta);
            
            % Calculate (x,y) location of "knee" joint:
            xKtheta = xHtheta + L1*cos(theta1);
            yKtheta = yHtheta + L1*sin(theta1);
            
            % Calculate knee angle:
            betatheta = wrapToPi(acos((L1^2 + L2^2 - xAptheta^2 - yAptheta^2)/(2*L1*L2)));
            theta2 = wrapToPi(pi - betatheta);
            
            % Calculate (x,y,angle) of "lower ankle" joint, using FK:
            xAtheta = xKtheta + L2*cos(theta1 + theta2);
            yAtheta = yKtheta + L2*sin(theta1 + theta2);
            theta3 = wrapToPi(angF - theta1 - theta2);
            
            %% IK for phi-chain:
            % Calculate (x,y) location of "hip" joint:
            xHphi = 0;
            yHphi = 0;
            
            % Calculate hip angle:
            gammaphi = wrapToPi(abs(atan2(yAu,xAu)));
            alphaphi = wrapToPi(acos((xAu^2 + yAu^2 + L3^2 - L4^2)/(2*L3*sqrt(xAu^2 + yAu^2))));
            phi1 = wrapToPi(-gammaphi - alphaphi);
            
            % Calculate (x,y) location of "knee" joint:
            xKphi = xHphi + L3*cos(phi1);
            yKphi = yHphi + L3*sin(phi1);
            
            % Calculate knee angle:
            betaphi = wrapToPi(acos((L3^2 + L4^2 - xAu^2 - yAu^2)/(2*L3*L4)));
            phi2 = wrapToPi(pi - betaphi);
            
            % Calculate (x,y,angle) of "upper ankle" joint, using FK:
            xAuphi = xKphi + L4*cos(phi1 + phi2);
            yAuphi = yKphi + L4*sin(phi1 + phi2);
            phi3 = wrapToPi(angF - phi1 - phi2);
            
            %% IK for psi-chain:
            % Calculate (x,y) location of "hip" joint:
            xHpsi = B2x;
            yHpsi = B2y;
            
            % Calculate hip angle:
            xAppsi = B2x - xA;
            yAppsi = B2y - yA;
            gammapsi = wrapToPi(abs(atan2(yAppsi,xAppsi)));
            alphapsi = wrapToPi(acos((xAppsi^2 + yAppsi^2 + L5^2 - L6^2)/(2*L5*sqrt(xAppsi^2 + yAppsi^2))));
            psi1 = wrapToPi(pi + gammapsi + alphapsi);
            
            % Calculate (x,y) location of "knee" joint:
            xKpsi = xHpsi + L5*cos(psi1);
            yKpsi = yHpsi + L5*sin(psi1);
            
            % Calculate knee angle:
            betapsi = wrapToPi(acos((L5^2 + L6^2 - xAppsi^2 - yAppsi^2)/(2*L5*L6)));
            psi2 = wrapToPi(pi + betapsi);
            
            % Calculate (x,y,angle) of "lower ankle" joint, using FK:
            xApsi = xKpsi + L6*cos(psi1 + psi2);
            yApsi = yKpsi + L6*sin(psi1 + psi2);
            psi3 = wrapToPi(angF - psi1 - psi2);
            
            %% Return joint angles:
            angles = [theta1    theta2  theta3;
                      phi1      phi2    phi3;
                      psi1      psi2    psi3];
            
            if plotOption
                plotRobot(app, footPose, lengths, angles);
            end % end if plotOption     
        end % end function subchainIK(...)
                    
        function plotRobot(app, footPose, lengths, angles)
            %% "unpack" link lengths from "lengths" input vector:
            % Note: this step is just for cleaner-looking code.
            L1 = lengths(1);
            L2 = lengths(2);
            L3 = lengths(3);
            L4 = lengths(4);
            L5 = lengths(5);
            L6 = lengths(6);
            L7 = lengths(7);
            L8 = lengths(8);
            B1x = lengths(9);
            B2x = lengths(10);
            B1y = lengths(11);
            B2y = lengths(12);
            
            %% "unpack" link lengths from "lengths" input matrix:
            theta1 = angles(1,1);
            theta2 = angles(1,2);
            % theta3 = angles(1,3);
            
            phi1 = angles(2,1);
            phi2 = angles(2,2);
            % phi3 = angles(2,3);
            
            psi1 = angles(3,1);
            psi2 = angles(3,2);
            % psi3 = angles(3,3);
            
            %% Extract foot pose:
            xF = footPose(1);
            yF = footPose(2);
            angF = footPose(3);
            
            % Calculate (x,y) location of "lower ankle" joint, from foot pose:
            xA = xF - L8*cos(angF);
            yA = yF - L8*sin(angF);
            
            % Calculate (x,y) location of "upper ankle" joint, from foot pose:
            xAu = xF - (L7+L8)*cos(angF);
            yAu = yF - (L7+L8)*sin(angF);
            
            %% FK for theta-chain:
            % Calculate (x,y) location of "hip" joint:
            xHtheta = -B1x;
            yHtheta = B1y;
            
            % Calculate (x,y) location of "knee" joint:
            xKtheta = xHtheta + L1*cos(theta1);
            yKtheta = yHtheta + L1*sin(theta1);
            
            % Calculate (x,y,angle) of "lower ankle" joint, using FK:
            xAtheta = xKtheta + L2*cos(theta1 + theta2);
            yAtheta = yKtheta + L2*sin(theta1 + theta2);
            
            %% FK for phi-chain:
            % Calculate (x,y) location of "hip" joint:
            xHphi = 0;
            yHphi = 0;
            
            % Calculate (x,y) location of "knee" joint:
            xKphi = xHphi + L3*cos(phi1);
            yKphi = yHphi + L3*sin(phi1);
            
            % Calculate (x,y,angle) of "upper ankle" joint, using FK:
            xAuphi = xKphi + L4*cos(phi1 + phi2);
            yAuphi = yKphi + L4*sin(phi1 + phi2);
            
            %% FK for psi-chain:
            % Calculate (x,y) location of "hip" joint:
            xHpsi = B2x;
            yHpsi = B2y;
            
            % Calculate (x,y) location of "knee" joint:
            xKpsi = xHpsi + L5*cos(psi1);
            yKpsi = yHpsi + L5*sin(psi1);
            
            % Calculate (x,y,angle) of "lower ankle" joint, using FK:
            xApsi = xKpsi + L6*cos(psi1 + psi2);
            yApsi = yKpsi + L6*sin(psi1 + psi2);
            
            %% Plot
            cla(app.UIAxes);
            plot(app.UIAxes,0,0,'ro','MarkerSize',14); % mark body frame location
            hold(app.UIAxes,'on');
            
            % theta-chain:
            line(app.UIAxes,[0,xHtheta],[0,yHtheta],'Color','k'); % draw link B1
            plot(app.UIAxes,xHtheta,yHtheta,'go','MarkerSize',10); % mark hip location (theta-chain)
            line(app.UIAxes,[xHtheta,xKtheta],[yHtheta,yKtheta],'Color','k'); % draw link 1
            plot(app.UIAxes,xKtheta,yKtheta,'go','MarkerSize',10); % mark knee location (theta-chain)
            line(app.UIAxes,[xKtheta,xAtheta],[yKtheta,yAtheta],'Color','k'); % draw link 2
            plot(app.UIAxes,xAtheta,yAtheta,'go','MarkerSize',14); % mark lower ankle location (calculated from FK)
            
            % phi-chain:
            plot(app.UIAxes,xAuphi,yAuphi,'go','MarkerSize',14); % mark upper ankle location (calculated from FK)
            line(app.UIAxes,[xHphi,xKphi],[yHphi,yKphi],'Color','k'); % draw link 3
            plot(app.UIAxes,xKphi,yKphi,'go','MarkerSize',10); % mark knee location (phi-chain)
            line(app.UIAxes,[xKphi,xAuphi],[yKphi,yAuphi],'Color','k'); % draw link 4
            
            % psi-chain:
            line(app.UIAxes,[0,xHpsi],[0,yHpsi],'Color','k'); % draw link B2
            plot(app.UIAxes,xHpsi,yHpsi,'go','MarkerSize',10); % mark hip location (psi-chain)
            line(app.UIAxes,[xHpsi,xKpsi],[yHpsi,yKpsi],'Color','k'); % draw link 5
            plot(app.UIAxes,xKpsi,yKpsi,'go','MarkerSize',10); % mark knee location (psi-chain)
            line(app.UIAxes,[xKpsi,xApsi],[yKpsi,yApsi],'Color','k'); % draw link 6
            plot(app.UIAxes,xApsi,yApsi,'go','MarkerSize',18); % mark lower ankle location (calculated from FK)
            
            % most distal leg (configuration determined by foot pose):
            plot(app.UIAxes, xAu,yAu,'go','MarkerSize',10); % mark upper ankle location (calculated from foot pose)
            line(app.UIAxes,[xAu,xA],[yAu,yA],'Color','k'); % draw link 7
            plot(app.UIAxes,xA,yA,'go','MarkerSize',10); % mark lower ankle location (calculated from foot pose)
            line(app.UIAxes,[xA,xF],[yA,yF],'Color','k'); % draw link 8
            plot(app.UIAxes,xF,yF,'bo','MarkerSize',12); % mark foot location        
        end % end plotRobot(...) function
        
        function [torques] = wrench2Torques(app,angles,lengths,wrench)
            %% Description:
            % uses the actuator Jacobian to map a specified end-effector wrench to joint torques
            % Inputs:
            %   angles: 3x3 array of joint angles in radians
            %   lengths: the relevant dimensions of the robot, stored in a 10x1 column
            %       vector according to this order:
            %       lengths = [L1; L2; L3; L4; L5 L6; L7; L8; B1x; B2x], where
            %       B1x, B1y, L1, L2, and L8 correspond to the theta-chain,
            %       L3, L4, L7, and L8 correspond to the phi-chain, and
            %       B2x, B2y, L5, L6, and L8 correspond to the psi-chain.
            %   wrench: end-effector wrench (3x1 column vector):
            %       wrench = [Fx; Fy; Tz];
            % Outputs:
            %   [torques]: joint torques (3x1 column vector)
            %       [torques] = [T1; T2; T3];
            % Dependencies:
            %   actuatorJacobian(...)
            %
            qa = [angles(1,1); angles(2,1); angles(3,1)];
            qu = [angles(1,2); angles(1,3); angles(2,2); angles(2,3); angles(3,2); angles(3,3)];
            Ja = actuatorJacobian(app, qa, qu, lengths, 1);
            torques = transpose(Ja)*wrench;
        end % end function wrench2Torques(...)
        
        function Ja = actuatorJacobian(app,qa,qu,lengths,chain)
            %% Description:
            % Calculates the actuator Jacobian
            %
            % Inputs:
            %   qa: actuated joint positions (3x1 column vector) 
            %       qa = [theta1; phi1; psi1];
            %   qu: unactuated joint positions (6x1 column vector)
            %       qu = [theta2; theta3; phi2; phi3; psi2; psi3];
            %   lengths: the relevant dimensions of the robot, stored in a 10x1 column
            %       vector according to this order:
            %       lengths = [L1; L2; L3; L4; L5 L6; L7; L8; B1x; B2x; B1y; B2y], where
            %       B1x, B1y, L1, L2, and L8 correspond to the theta-chain,
            %       L3, L4, L7, and L8 correspond to the phi-chain, and
            %       B2x, B2y, L5, L6, and L8 correspond to the psi-chain.
            %   chain: the actuator Jacobian will be calculated using this open
            %   subchain's Jacobian.
            %       chain = 1: use Jtheta (theta-chain Jacobian)
            %       chain = 2: use Jphi (phi-chain Jacobian)
            %       chain = 3: use Jpsi (psi-chain Jacobian
            %
            % Outputs:
            %   Ja: the actuator Jacobian, a 3x3 matrix.
            %
            % Dependencies:
            %   constraintJacobian(...)
            %   subchainJacobian(...)
            %
            %% Get the constraint Jacobian
            Jc = constraintJacobian(app,qa,qu,lengths);
            
            %% Get the all open subchain Jacobians
            Jtheta = subchainJacobian(app,qa,qu,lengths,1);
            Jphi = subchainJacobian(app,qa,qu,lengths,2);
            Jpsi = subchainJacobian(app,qa,qu,lengths,3);
            
            %% Form Ha from open subchain Jacobians
            Ha = [Jtheta(1,1), -Jphi(1,1), 0;
                  Jtheta(2,1), -Jphi(2,1), 0;
                  Jtheta(3,1), -Jphi(3,1), 0;
                  0, -Jphi(1,1), Jpsi(1,1);
                  0, -Jphi(2,1), Jpsi(2,1);
                  0, -Jphi(3,1), Jpsi(3,1)];
              
            %% Return the specified actuator Jacobian
            switch chain
                case 1
                    Ja = Jtheta*[1 0 0;
                        [1 0 0 0 0 0]*(-inv(Jc))*Ha;
                        [0 1 0 0 0 0]*(-inv(Jc))*Ha];
                case 2
                    Ja = Jphi*[0 1 0;
                        [0 0 1 0 0 0]*(-inv(Jc))*Ha;
                        [0 0 0 1 0 0]*(-inv(Jc))*Ha];
                case 3
                    Ja = Jpsi*[0 0 1;
                        [0 0 0 0 1 0]*(-inv(Jc))*Ha;
                        [0 0 0 0 0 1]*(-inv(Jc))*Ha];
                otherwise
                    error('actuatorJacobian error: unknown open subchain selection');
            end
        end % end function actuatorJacobian(...)
        
        
        function Jc = constraintJacobian(app,qa,qu,lengths)
        %% Description:
        % Calculates the constraint Jacobian, i.e., the matrix of partial
        % derivatives of the foot pose with respect to the unactuated joint
        % positions.
        %
        % Inputs:
        %   qa: actuated joint positions (3x1 column vector) 
        %       qa = [theta1; phi1; psi1];
        %   qu: unactuated joint positions (6x1 column vector)
        %       qu = [theta2; theta3; phi2; phi3; psi2; psi3];
        %   lengths: the relevant dimensions of the robot, stored in a 10x1 column
        %       vector according to this order:
        %       lengths = [L1; L2; L3; L4; L5 L6; L7; L8; B1x; B2x], where
        %       B1x, B1y, L1, L2, and L8 correspond to the theta-chain,
        %       L3, L4, L7, and L8 correspond to the phi-chain, and
        %       B2x, B2y, L5, L6, and L8 correspond to the psi-chain.
        %
        % Outputs:
        %   Jc: the constraint Jacobian, a 6x6 matrix.
        %
        % Dependencies:
        %   none
        %
        
        %% "unpack" link lengths from "lengths" input vector:
        % Note: this step is just for cleaner-looking code.
        % Also note: not all lengths are used when computing the constraint
        %   Jacobian.
        L1 = lengths(1);
        L2 = lengths(2);
        L3 = lengths(3);
        L4 = lengths(4);
        L5 = lengths(5);
        L6 = lengths(6);
        L7 = lengths(7);
        L8 = lengths(8);
        B1x = lengths(9);
        B2x = lengths(10);
        B1y = lengths(11);
        B2y = lengths(12);
        
        %% "unpack" individual angles from "qa" and "qu" input vectors:
        % Note: this step is just for cleaner-looking code.
        th1 = qa(1); th2 = qu(1); th3 = qu(2); % theta-chain
        ph1 = qa(2); ph2 = qu(3); ph3 = qu(4); % phi-chain
        ps1 = qa(3); ps2 = qu(5); ps3 = qu(6); % psi-chain
        
        %% Compute each partial derivative:
        % each J... matrix is 3x1
        Jth2 = [-L2*sin(th1+th2) - L8*sin(th1+th2+th3);
                L2*cos(th1+th2) + L8*cos(th1+th2+th3);
                1];
        Jth3 = [-L8*sin(th1+th2+th3);
                L8*cos(th1+th2+th3);
                1];
        Jph2 = [-L4*sin(ph1+ph2) - (L7+L8)*sin(ph1+ph2+ph3);
                L4*cos(ph1+ph2) + (L7+L8)*cos(ph1+ph2+ph3);
                1];
        Jph3 = [- (L7+L8)*sin(ph1+ph2+ph3);
                (L7+L8)*cos(ph1+ph2+ph3);
                1];
        Jps2 = [-L6*sin(ps1+ps2) - L8*sin(ps1+ps2+ps3);
                L6*cos(ps1+ps2) + L8*cos(ps1+ps2+ps3);
                1];
        Jps3 = [-L8*sin(ps1+ps2+ps3);
                L8*cos(ps1+ps2+ps3);
                1];
        
        %% Combine partial derivatives into 6x6 constraint Jacobian:
        Jc = [Jth2,         Jth3,       -Jph2,  -Jph3,  zeros(3,1), zeros(3,1);
              zeros(3,1),   zeros(3,1), -Jph2,  -Jph3,  Jps2,       Jps3];
        end
        
        
        function J = subchainJacobian(app,qa,qu,lengths,chain)
            %% Description:
            % Calculates the Jacobian of the subchain specified by 'chain'
            %
            % Inputs:
            %   qa: actuated joint positions (3x1 column vector) 
            %       qa = [theta1; phi1; psi1];
            %   qu: unactuated joint positions (6x1 column vector)
            %       qu = [theta2; theta3; phi2; phi3; psi2; psi3];
            %   lengths: the relevant dimensions of the robot, stored in a 10x1 column
            %       vector according to this order:
            %       lengths = [L1; L2; L3; L4; L5 L6; L7; L8; B1x; B2x], where
            %       B1x, B1y, L1, L2, and L8 correspond to the theta-chain,
            %       L3, L4, L7, and L8 correspond to the phi-chain, and
            %       B2x, B2y, L5, L6, and L8 correspond to the psi-chain.
            %   chain: the Jacobian will be calculated for this open
            %   subchain.
            %       chain = 1: theta-chain Jacobian
            %       chain = 2: phi-chain Jacobian
            %       chain = 3: psi-chain Jacobia
            %
            % Outputs:
            %   J: the actuator Jacobian, a 3x3 matrix.
            %
            % Dependencies:
            %   none
            %
            %% "unpack" link lengths from "lengths" input vector:
            % Note: this step is just for cleaner-looking code.
            % Also note: not all lengths are used when computing the constraint
            %   Jacobian.
            L1 = lengths(1);
            L2 = lengths(2);
            L3 = lengths(3);
            L4 = lengths(4);
            L5 = lengths(5);
            L6 = lengths(6);
            L7 = lengths(7);
            L8 = lengths(8);
            B1x = lengths(9);
            B2x = lengths(10);
            B1y = lengths(11);
            B2y = lengths(12);
            
            %% "unpack" individual angles from "qa" and "qu" input vectors:
            % Note: this step is just for cleaner-looking code.
            th1 = qa(1); th2 = qu(1); th3 = qu(2); % theta-chain
            ph1 = qa(2); ph2 = qu(3); ph3 = qu(4); % phi-chain
            ps1 = qa(3); ps2 = qu(5); ps3 = qu(6); % psi-chain
            
            %% 
            switch chain
                case 1 % theta-chain
                    J = [-L1*sin(th1) - L2*sin(th1+th2) - L8*sin(th1+th2+th3), ...
                        -L2*sin(th1+th2) - L8*sin(th1+th2+th3), ...
                        -L8*sin(th1+th2+th3);
                        L1*cos(th1) + L2*cos(th1+th2) + L8*cos(th1+th2+th3), ...
                        L2*cos(th1+th2) + L8*cos(th1+th2+th3), ...
                        L8*cos(th1+th2+th3);
                        1,1,1];
                case 2 % phi-chain
                    J = [-L3*sin(ph1) - L4*sin(ph1+ph2) - (L7+L8)*sin(ph1+ph2+ph3), ...
                        -L4*sin(ph1+ph2) - (L7+L8)*sin(ph1+ph2+ph3), ...
                        -(L7+L8)*sin(ph1+ph2+ph3);
                        L3*cos(ph1) + L4*cos(ph1+ph2) + (L7+L8)*cos(ph1+ph2+ph3), ...
                        L4*cos(ph1+ph2) + (L7+L8)*cos(ph1+ph2+ph3), ...
                        (L7+L8)*cos(ph1+ph2+ph3);
                        1,1,1];
                case 3 % psi-chain
                    J = [-L5*sin(ps1) - L6*sin(ps1+ps2) - L8*sin(ps1+ps2+ps3), ...
                        -L6*sin(ps1+ps2) - L8*sin(ps1+ps2+ps3), ...
                        -L8*sin(ps1+ps2+ps3);
                        L5*cos(ps1) + L6*cos(ps1+ps2) + L8*cos(ps1+ps2+ps3), ...
                        L6*cos(ps1+ps2) + L8*cos(ps1+ps2+ps3), ...
                        L8*cos(ps1+ps2+ps3);
                        1,1,1];
                otherwise
                    error('subchainJacobian error: unknown open subchain selection');
            end % end "switch chain..."            
        end
        
        function [footPose,qu] = geomFK(app,qa,lengths,solOption)
            % Description:
            %   Computes the foot pose, using the actuated joint positions.
            %   Uses geometry (intersecting circles) to solve for the locations of the 
            %   "ankle" (xA,yA) and the "upper ankle" (xuA, yuA) relative to the base 
            %   frame, then calculates unactuated joint positions and the foot pose.
            %
            % References:
            %   https://math.stackexchange.com/questions/256100/how-can-i-find-the-points-at-which-two-circles-intersect
            %   http://mathworld.wolfram.com/Circle-CircleIntersection.html
            %
            % Inputs:
            %   qa:     actuated joint positions (column vector)
            %       qa = [theta1; phi1; psi1];
            %   lengths: the relevant dimensions of the robot, stored in a 10x1 column
            %       vector according to this order:
            %       lengths = [L1; L2; L3; L4; L5 L6; L7; L8; B1x; B2x; B1y; B2y], where
            %       B1x, B1y, L1, L2, and L8 correspond to the theta-chain,
            %       L3, L4, L7, and L8 correspond to the phi-chain, and
            %       B2x, B2y, L5, L6, and L8 correspond to the psi-chain.
            %   solOption: 1, 2, 3, or 4
            %       1: function uses (xA1,yA1) and (xuA1, yuA1)
            %       2: function uses (xA1,yA1) and (xuA2, yuA2)
            %       3: function uses (xA2,yA2) and (xuA1, yuA1)
            %       4: function uses (xA2,yA2) and (xuA2, yuA2)
            %
            % Outputs:
            %   qu:         6x1 column vector
            %       qu = [th2; th3; ph; ph3; ps2; ps3];
            %   footPose:   3-element row vector which comprises:
            %       xF:     x-coordinate of the foot, relative to the body
            %       yF:     y-coordinate of the foot, relative to the body
            %       angF:   angle of the foot, relative to the body.
            %
            % Dependencies:
            %   none
            
            %% "unpack" link lengths from "lengths" input vector:
            % Note: this step is just for cleaner-looking code.
            L1 = lengths(1);
            L2 = lengths(2);
            L3 = lengths(3);
            L4 = lengths(4);
            L5 = lengths(5);
            L6 = lengths(6);
            L7 = lengths(7);
            L8 = lengths(8);
            B1x = lengths(9);
            B2x = lengths(10);
            B1y = lengths(11);
            B2y = lengths(12);
            
            %% "unpack" actuated joint angles from "qa" input vector:
            % Note: this step is just for cleaner-looking code.
            th1 = qa(1);
            ph1 = qa(2);
            ps1 = qa(3);
            
            %% create empty arrays:
            qu = zeros(6,1);
            footPose = zeros(3,1);
            
            %% Calculate (xA, yA) using th1 and ps1:
            xkth = -B1x + L1*cos(th1);  % x-coordinate of theta-chain "knee"
            ykth = B1y + L1*sin(th1);   % y-coordinate of theta-chain "knee"
            
            xkps = B2x + L5*cos(ps1);  % x-coordinate of psi-chain "knee"
            ykps = B2y + L5*sin(ps1);   % y-coordinate of psi-chain "knee"
            
            a = sqrt((xkth - xkps)^2 + (ykth - ykps)^2);    % intermediate variable
            b = (L2^2 - L6^2 + a^2)/(2*a);                  % intermediate variable
            c = sqrt(L2^2 - b^2);                           % intermediate variable
            
            xA1 = (b/a)*(xkps - xkth) + (c/a)*(ykps - ykth) + xkth;   % solution 1/2 xA-coordinate
            xA2 = (b/a)*(xkps - xkth) - (c/a)*(ykps - ykth) + xkth;   % solution 3/4 xA-coordinate
            
            yA1 = (b/a)*(ykps - ykth) - (c/a)*(xkps - xkth) + ykth;   % solution 1/2 yA-coordinate
            yA2 = (b/a)*(ykps - ykth) + (c/a)*(xkps - xkth) + ykth;   % solution 3/4 yA-coordinate
            
            if (solOption==1 || solOption==2)
                xA = xA1;
                yA = yA1;
            else
                xA = xA2;
                yA = yA2;
            end
            % fprintf('(xA, yA) = (%f, %f)\n',[xA, yA]);
            
            %% Calculate (xuA, yuA) using ph1 and (xA, yA):
            xkph = L3*cos(ph1);  % x-coordinate of phi-chain "knee"
            ykph = L3*sin(ph1);   % y-coordinate of phi-chain "knee"
            
            d = sqrt((xkph - xA)^2 + (ykph - yA)^2);    % intermediate variable
            e = (L4^2 - L7^2 + d^2)/(2*d);              % intermediate variable
            f = sqrt(L4^2 - e^2);                       % intermediate variable
            
            xuA1 = (e/d)*(xA - xkph) - (f/d)*(yA - ykph) + xkph;   % solution 1/3 xuA-coordinate
            xuA2 = (e/d)*(xA - xkph) + (f/d)*(yA - ykph) + xkph;   % solution 2/4 xuA-coordinate
            
            yuA1 = (e/d)*(yA - ykph) + (f/d)*(xA - xkph) + ykph;   % solution 1/3 xuA-coordinate
            yuA2 = (e/d)*(yA - ykph) - (f/d)*(xA - xkph) + ykph;   % solution 2/4 xuA-coordinate
            
            if (solOption==1 || solOption==3)
                xuA = xuA1;
                yuA = yuA1;
            else
                xuA = xuA2;
                yuA = yuA2;
            end
            % fprintf('(xuA, yuA) = (%f, %f)\n',[xuA, yuA]);
            
            %% Solve for "knee" angles (th2, ph2, ps2):
            
            % theta-chain--------------------------------------------------------------
            % Calculate (x,y) location of theta-chain "hip" joint:
            xHtheta = -B1x;
            yHtheta = B1y;
            
            % ankle relative to theta-hip:
            xAptheta = -xHtheta + xA;
            yAptheta = yHtheta - yA;
            
            % Calculate knee angle:
            betatheta = wrapToPi(acos((L1^2 + L2^2 - xAptheta^2 - yAptheta^2)/(2*L1*L2)));
            th2 = wrapToPi(pi - betatheta);
            
            % fprintf('theta2 = %f degrees\n',th2*(180/pi));
            
            % save th2 to qu array:
            qu(1) = th2;
            
            % phi-chain----------------------------------------------------------------
            % Calculate knee angle:
            betaphi = wrapToPi(acos((L3^2 + L4^2 - xuA^2 - yuA^2)/(2*L3*L4)));
            ph2 = wrapToPi(pi - betaphi);
            
            % fprintf('phi2 = %f degrees\n',ph2*(180/pi));
            
            % save ph2 to qu array:
            qu(3) = ph2;
            
            % psi-chain----------------------------------------------------------------
            % Calculate (x,y) location of "hip" joint:
            xHpsi = B2x;
            yHpsi = B2y;
            
            % Calculate hip angle:
            xAppsi = xHpsi - xA;
            yAppsi = yHpsi - yA;
            
            % Calculate knee angle:
            betapsi = wrapToPi(acos((L5^2 + L6^2 - xAppsi^2 - yAppsi^2)/(2*L5*L6)));
            ps2 = wrapToPi(pi + betapsi);
            
            % fprintf('psi2 = %f degrees\n',ps2*(180/pi));
            
            % save ps2 to qu array:
            qu(5) = ps2;
            
            %% Calculate the foot pose:
            footAngle = wrapToPi(pi+ atan2(yuA - yA, xuA - xA));
            footX = xA + L8*cos(footAngle);
            footY = yA + L8*sin(footAngle);
            
            % "pack" foot pose variables into footPose:
            footPose(1) = footX;
            footPose(2) = footY;
            footPose(3) = footAngle;
            
            %% Calculate the third angle in each open chain:
            th3 = footAngle - th1 - th2;
            ph3 = footAngle - ph1 - ph2;
            ps3 = footAngle - ps1 - ps2;
            
            qu(2) = th3;
            qu(4) = ph3;
            qu(6) = ps3; 
            
        end
        
    end % end methods (Access = public)


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TabGroup.SelectedTab = app.InverseKinematicsTab;
            
            app.lengths = app.defaultLengths;
            
            app.Link1Slider.Value = app.lengths(1);
            app.Link2Slider.Value = app.lengths(2);
            app.Link3Slider.Value = app.lengths(3);
            app.Link4Slider.Value = app.lengths(4);
            app.Link5Slider.Value = app.lengths(5);
            app.Link6Slider.Value = app.lengths(6);
            app.Link7Slider.Value = app.lengths(7);
            app.Link8Slider.Value = app.lengths(8);
            app.LR_MxSlider.Value = app.lengths(9);
            app.LR_MySlider.Value = app.lengths(11);
            
            app.EditField_7.Value = app.lengths(1);
            app.EditField_8.Value = app.lengths(2);
            app.EditField_9.Value = app.lengths(3);
            app.EditField_10.Value = app.lengths(4);
            app.EditField_11.Value = app.lengths(5);
            app.EditField_12.Value = app.lengths(6);
            app.EditField_13.Value = app.lengths(7);
            app.EditField_14.Value = app.lengths(8);
            app.EditField_15.Value = app.lengths(9);
            app.EditField_16.Value = app.lengths(11);
            
            app.footPoseDeg = app.defaultFootPoseDeg;
            app.footPose = app.footPoseDeg;
            app.footPose(3) = app.footPoseDeg(3)*pi/180;
            
            app.FootXPosKnob.Value = app.footPoseDeg(1);
            app.FootYPosKnob.Value = app.footPoseDeg(2);
            app.FootAngleKnob.Value = app.footPoseDeg(3);
            app.EditField_4.Value = app.footPoseDeg(1);
            app.EditField_5.Value = app.footPoseDeg(2);
            app.EditField_6.Value = app.footPoseDeg(3);
            
            app.wrench = app.defaultWrench;
            app.FxEditField.Value = app.wrench(1);
            app.FyEditField.Value = app.wrench(2);
            app.TzEditField.Value = app.wrench(3);
            
            try
                [angles] = subchainIK(app,app.footPose,app.lengths,1);
                app.angles = angles;
                
                app.Joint1AngleKnob.Value = angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = angles(3,1)*(180/pi);
                app.EditField.Value = angles(1,1)*(180/pi);
                app.EditField_2.Value = angles(2,1)*(180/pi);
                app.EditField_3.Value = angles(3,1)*(180/pi);
                
                try
                   [torques] = wrench2Torques(app,angles,app.lengths,app.wrench);
                   app.torques = torques;
                   app.T1EditField.Value = app.torques(1);
                   app.T2EditField.Value = app.torques(2);
                   app.T3EditField.Value = app.torques(3);
                catch
                    fprintf("torque calculation failed.\n")
                end
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
            
            app.wrench = app.defaultWrench;
        end

        % Value changed function: Link1Slider
        function Link1SliderValueChanged(app, event)
            app.EditField_7.Value = app.Link1Slider.Value;
            
            app.Link5Slider.Value = app.Link1Slider.Value;  % enforce symmetry
            app.EditField_11.Value = app.Link1Slider.Value; % enforce symmetry
            app.lengths(1) = app.Link1Slider.Value;
            app.lengths(5) = app.Link1Slider.Value;         % enforce symmetry
            
            try
                [angles] = subchainIK(app,app.footPose,app.lengths,1);
                
                app.Joint1AngleKnob.Value = angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = angles(3,1)*(180/pi);
                app.EditField.Value = angles(1,1)*(180/pi);
                app.EditField_2.Value = angles(2,1)*(180/pi);
                app.EditField_3.Value = angles(3,1)*(180/pi);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            app.Link1Slider.Value = app.EditField_7.Value;
            app.lengths(1) = app.EditField_7.Value;
            app.EditField_11.Value = app.EditField_7.Value;
            app.Link5Slider.Value = app.EditField_7.Value;
            app.lengths(5) = app.EditField_7.Value;
            
            try
                [angles] = subchainIK(app,app.footPose,app.lengths,1);
                app.angles = angles;
                
                app.Joint1AngleKnob.Value = angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = angles(3,1)*(180/pi);
                app.EditField.Value = angles(1,1)*(180/pi);
                app.EditField_2.Value = angles(2,1)*(180/pi);
                app.EditField_3.Value = angles(3,1)*(180/pi);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
        end

        % Value changed function: Link2Slider
        function Link2SliderValueChanged(app, event)
            app.EditField_8.Value = app.Link2Slider.Value;
            app.lengths(2) = app.Link2Slider.Value;
            app.lengths(6) = app.Link2Slider.Value;         % enforce symmetry
            app.Link6Slider.Value = app.Link2Slider.Value;  % enforce symmetry
            app.EditField_12.Value = app.Link2Slider.Value; % enforce symmetry
            
            try
                [angles] = subchainIK(app,app.footPose,app.lengths,1);
                app.angles = angles;
                
                app.Joint1AngleKnob.Value = angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = angles(3,1)*(180/pi);
                app.EditField.Value = angles(1,1)*(180/pi);
                app.EditField_2.Value = angles(2,1)*(180/pi);
                app.EditField_3.Value = angles(3,1)*(180/pi);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            app.Link2Slider.Value = app.EditField_8.Value;
            app.lengths(2) = app.EditField_8.Value;
            app.lengths(6) = app.EditField_8.Value;         % enforce symmetry
            app.Link6Slider.Value = app.EditField_8.Value;  % enforce symmetry
            app.EditField_12.Value = app.EditField_8.Value;    % enforce symmetry
            
            try
                [angles] = subchainIK(app,app.footPose,app.lengths,1);
                
                app.Joint1AngleKnob.Value = angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = angles(3,1)*(180/pi);
                app.EditField.Value = angles(1,1)*(180/pi);
                app.EditField_2.Value = angles(2,1)*(180/pi);
                app.EditField_3.Value = angles(3,1)*(180/pi);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
        end

        % Value changed function: Link3Slider
        function Link3SliderValueChanged(app, event)
            app.EditField_9.Value = app.Link3Slider.Value;
            app.lengths(3) = app.Link3Slider.Value;
            
            try
                [angles] = subchainIK(app,app.footPose,app.lengths,1);
                app.angles = angles;
                
                app.Joint1AngleKnob.Value = angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = angles(3,1)*(180/pi);
                app.EditField.Value = angles(1,1)*(180/pi);
                app.EditField_2.Value = angles(2,1)*(180/pi);
                app.EditField_3.Value = angles(3,1)*(180/pi);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
        end

        % Value changed function: EditField_9
        function EditField_9ValueChanged(app, event)
            app.Link3Slider.Value = app.EditField_9.Value;
            app.lengths(3) = app.EditField_9.Value;
            
            try
                [angles] = subchainIK(app,app.footPose,app.lengths,1);
                app.angles = angles;
                
                app.Joint1AngleKnob.Value = angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = angles(3,1)*(180/pi);
                app.EditField.Value = angles(1,1)*(180/pi);
                app.EditField_2.Value = angles(2,1)*(180/pi);
                app.EditField_3.Value = angles(3,1)*(180/pi);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
        end

        % Value changed function: Link4Slider
        function Link4SliderValueChanged(app, event)
            app.EditField_10.Value = app.Link4Slider.Value;
            app.lengths(4) = app.Link4Slider.Value;
            
            try
                [angles] = subchainIK(app,app.footPose,app.lengths,1);
                app.angles = angles;
                
                app.Joint1AngleKnob.Value = angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = angles(3,1)*(180/pi);
                app.EditField.Value = angles(1,1)*(180/pi);
                app.EditField_2.Value = angles(2,1)*(180/pi);
                app.EditField_3.Value = angles(3,1)*(180/pi);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
        end

        % Value changed function: EditField_10
        function EditField_10ValueChanged(app, event)
            app.Link4Slider.Value = app.EditField_10.Value;
            app.lengths(4) = app.EditField_10.Value;
            
            try
                [angles] = subchainIK(app,app.footPose,app.lengths,1);
                app.angles = angles;
                
                app.Joint1AngleKnob.Value = angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = angles(3,1)*(180/pi);
                app.EditField.Value = angles(1,1)*(180/pi);
                app.EditField_2.Value = angles(2,1)*(180/pi);
                app.EditField_3.Value = angles(3,1)*(180/pi);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
        end

        % Value changed function: Link5Slider
        function Link5SliderValueChanged(app, event)
            app.EditField_11.Value = app.Link5Slider.Value;
            app.lengths(5) = app.Link5Slider.Value;
            app.lengths(1) = app.Link5Slider.Value;
            app.Link1Slider.Value = app.Link5Slider.Value;  % enforce symmetry
            app.EditField_7.Value = app.Link5Slider.Value;  % enforce symmetry
            
            try
                [angles] = subchainIK(app,app.footPose,app.lengths,1);
                app.angles = angles;
                
                app.Joint1AngleKnob.Value = angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = angles(3,1)*(180/pi);
                app.EditField.Value = angles(1,1)*(180/pi);
                app.EditField_2.Value = angles(2,1)*(180/pi);
                app.EditField_3.Value = angles(3,1)*(180/pi);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
        end

        % Value changed function: EditField_11
        function EditField_11ValueChanged(app, event)
            app.Link5Slider.Value = app.EditField_11.Value;
            app.lengths(5) = app.EditField_11.Value;
            app.lengths(1) = app.EditField_11.Value;        % enforce symmetry
            app.Link1Slider.Value = app.EditField_11.Value;  % enforce symmetry
            app.EditField_7.Value = app.EditField_11.Value; % enforce symmetry
            
            try
                [angles] = subchainIK(app,app.footPose,app.lengths,1);
                app.angles = angles;
                
                app.Joint1AngleKnob.Value = angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = angles(3,1)*(180/pi);
                app.EditField.Value = angles(1,1)*(180/pi);
                app.EditField_2.Value = angles(2,1)*(180/pi);
                app.EditField_3.Value = angles(3,1)*(180/pi);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
        end

        % Value changed function: Link6Slider
        function Link6SliderValueChanged(app, event)
            app.EditField_12.Value = app.Link6Slider.Value;
            app.lengths(6) = app.Link6Slider.Value;
            app.lengths(2) = app.Link6Slider.Value;         % enforce symmetry
            app.Link2Slider.Value = app.Link6Slider.Value;  % enforce symmetry
            app.EditField_8.Value = app.Link6Slider.Value;  % enforce symmetry
            
            try
                [angles] = subchainIK(app,app.footPose,app.lengths,1);
                app.angles = angles;
                
                app.Joint1AngleKnob.Value = angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = angles(3,1)*(180/pi);
                app.EditField.Value = angles(1,1)*(180/pi);
                app.EditField_2.Value = angles(2,1)*(180/pi);
                app.EditField_3.Value = angles(3,1)*(180/pi);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
        end

        % Value changed function: EditField_12
        function EditField_12ValueChanged(app, event)
            app.Link6Slider.Value = app.EditField_12.Value;
            app.lengths(6) = app.EditField_12.Value;
            app.lengths(2) = app.EditField_12.Value;        % enforce symmetry
            app.Link2Slider.Value = app.Link6Slider.Value;  % enforce symmetry
            app.EditField_8.Value = app.EditField_12.Value; % enforce symmetry
            
            try
                [angles] = subchainIK(app,app.footPose,app.lengths,1);
                app.angles = angles;
                
                app.Joint1AngleKnob.Value = angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = angles(3,1)*(180/pi);
                app.EditField.Value = angles(1,1)*(180/pi);
                app.EditField_2.Value = angles(2,1)*(180/pi);
                app.EditField_3.Value = angles(3,1)*(180/pi);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
        end

        % Value changed function: Link7Slider
        function Link7SliderValueChanged(app, event)
            app.EditField_13.Value = app.Link7Slider.Value;
            app.lengths(7) = app.Link7Slider.Value;
            
            try
                [angles] = subchainIK(app,app.footPose,app.lengths,1);
                app.angles = angles;
                
                app.Joint1AngleKnob.Value = angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = angles(3,1)*(180/pi);
                app.EditField.Value = angles(1,1)*(180/pi);
                app.EditField_2.Value = angles(2,1)*(180/pi);
                app.EditField_3.Value = angles(3,1)*(180/pi);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
        end

        % Value changed function: EditField_13
        function EditField_13ValueChanged(app, event)
            app.Link7Slider.Value = app.EditField_13.Value;
            app.lengths(7) = app.EditField_13.Value;
            
            try
                [angles] = subchainIK(app,app.footPose,app.lengths,1);
                app.angles = angles;
                
                app.Joint1AngleKnob.Value = angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = angles(3,1)*(180/pi);
                app.EditField.Value = angles(1,1)*(180/pi);
                app.EditField_2.Value = angles(2,1)*(180/pi);
                app.EditField_3.Value = angles(3,1)*(180/pi);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
        end

        % Value changed function: Link8Slider
        function Link8SliderValueChanged(app, event)
            app.EditField_14.Value = app.Link8Slider.Value;
            app.lengths(8) = app.Link8Slider.Value;
            
            try
                [angles] = subchainIK(app,app.footPose,app.lengths,1);
                app.angles = angles;
                
                app.Joint1AngleKnob.Value = angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = angles(3,1)*(180/pi);
                app.EditField.Value = angles(1,1)*(180/pi);
                app.EditField_2.Value = angles(2,1)*(180/pi);
                app.EditField_3.Value = angles(3,1)*(180/pi);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
        end

        % Value changed function: EditField_14
        function EditField_14ValueChanged(app, event)
            app.Link8Slider.Value = app.EditField_14.Value;
            app.lengths(8) = app.EditField_14.Value;
            
            try
                [angles] = subchainIK(app,app.footPose,app.lengths,1);
                app.angles = angles;
                
                app.Joint1AngleKnob.Value = angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = angles(3,1)*(180/pi);
                app.EditField.Value = angles(1,1)*(180/pi);
                app.EditField_2.Value = angles(2,1)*(180/pi);
                app.EditField_3.Value = angles(3,1)*(180/pi);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
        end

        % Value changed function: LR_MxSlider
        function LR_MxSliderValueChanged(app, event)
            app.EditField_15.Value = app.LR_MxSlider.Value;
            app.lengths(9) = app.LR_MxSlider.Value;
            app.lengths(10) = app.LR_MxSlider.Value; % enforce symmetry
            
            try
                [angles] = subchainIK(app,app.footPose,app.lengths,1);
                app.angles = angles;
                
                app.Joint1AngleKnob.Value = angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = angles(3,1)*(180/pi);
                app.EditField.Value = angles(1,1)*(180/pi);
                app.EditField_2.Value = angles(2,1)*(180/pi);
                app.EditField_3.Value = angles(3,1)*(180/pi);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
        end

        % Value changed function: EditField_15
        function EditField_15ValueChanged(app, event)
            app.LR_MxSlider.Value = app.EditField_15.Value;
            app.lengths(9) = app.EditField_15.Value;
            app.lengths(10) = app.EditField_15.Value;   % enforce symmetry
            
            try
                [angles] = subchainIK(app,app.footPose,app.lengths,1);
                app.angles = angles;
                
                app.Joint1AngleKnob.Value = angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = angles(3,1)*(180/pi);
                app.EditField.Value = angles(1,1)*(180/pi);
                app.EditField_2.Value = angles(2,1)*(180/pi);
                app.EditField_3.Value = angles(3,1)*(180/pi);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
        end

        % Value changed function: LR_MySlider
        function LR_MySliderValueChanged(app, event)
            app.EditField_16.Value = app.LR_MySlider.Value;
            app.lengths(11) = app.LR_MySlider.Value;
            app.lengths(12) = app.LR_MySlider.Value;    % enforce symmetry
            
            try
                [angles] = subchainIK(app,app.footPose,app.lengths,1);
                app.angles = angles;
                
                app.Joint1AngleKnob.Value = angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = angles(3,1)*(180/pi);
                app.EditField.Value = angles(1,1)*(180/pi);
                app.EditField_2.Value = angles(2,1)*(180/pi);
                app.EditField_3.Value = angles(3,1)*(180/pi);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
        end

        % Value changed function: EditField_16
        function EditField_16ValueChanged(app, event)
            app.LR_MySlider.Value = app.EditField_16.Value;
            app.lengths(11) = app.EditField_16.Value;
            app.lengths(12) = app.EditField_16.Value;   % enforce symmetry
            
            try
                [angles] = subchainIK(app,app.footPose,app.lengths,1);
                app.angles = angles;
                
                app.Joint1AngleKnob.Value = angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = angles(3,1)*(180/pi);
                app.EditField.Value = angles(1,1)*(180/pi);
                app.EditField_2.Value = angles(2,1)*(180/pi);
                app.EditField_3.Value = angles(3,1)*(180/pi);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
        end

        % Value changed function: Joint1AngleKnob
        function Joint1AngleKnobValueChanged(app, event)
            app.EditField.Value = app.Joint1AngleKnob.Value;
            app.angles(1,1) = app.Joint1AngleKnob.Value*(pi/180);
            
            qa = [app.angles(1,1); app.angles(2,1); app.angles(3,1)];
            
            try
                [app.footPose,qu] = geomFK(app,qa,app.lengths,1);
                
                app.angles = [qa(1), qu(1), qu(2);
                      qa(2), qu(3), qu(4);
                      qa(3), qu(5), qu(6)];
                
                app.Joint1AngleKnob.Value = app.angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = app.angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = app.angles(3,1)*(180/pi);
                app.EditField.Value = app.angles(1,1)*(180/pi);
                app.EditField_2.Value = app.angles(2,1)*(180/pi);
                app.EditField_3.Value = app.angles(3,1)*(180/pi);
                
                app.FootXPosKnob.Value = app.footPose(1);
                app.FootYPosKnob.Value = app.footPose(2);
                app.FootAngleKnob.Value = app.footPose(3)*(180/pi);
                app.EditField_4.Value = app.footPose(1);
                app.EditField_5.Value = app.footPose(2);
                app.EditField_6.Value = app.footPose(3)*(180/pi);
                
                plotRobot(app, app.footPose, app.lengths, app.angles);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.Joint1AngleKnob.Value = app.EditField.Value;
            app.angles(1,1) = app.EditField.Value*(pi/180);
            
            qa = [app.angles(1,1); app.angles(2,1); app.angles(3,1)];
            
            try
                [app.footPose,qu] = geomFK(app,qa,app.lengths,1);
                
                app.angles = [qa(1), qu(1), qu(2);
                      qa(2), qu(3), qu(4);
                      qa(3), qu(5), qu(6)];
                
                app.Joint1AngleKnob.Value = app.angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = app.angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = app.angles(3,1)*(180/pi);
                app.EditField.Value = app.angles(1,1)*(180/pi);
                app.EditField_2.Value = app.angles(2,1)*(180/pi);
                app.EditField_3.Value = app.angles(3,1)*(180/pi);
                
                app.FootXPosKnob.Value = app.footPose(1);
                app.FootYPosKnob.Value = app.footPose(2);
                app.FootAngleKnob.Value = app.footPose(3)*(180/pi);
                app.EditField_4.Value = app.footPose(1);
                app.EditField_5.Value = app.footPose(2);
                app.EditField_6.Value = app.footPose(3)*(180/pi);
                
                plotRobot(app, app.footPose, app.lengths, app.angles);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
        end

        % Value changed function: Joint2AngleKnob
        function Joint2AngleKnobValueChanged(app, event)
            app.EditField_2.Value = app.Joint2AngleKnob.Value;
            app.angles(2,1) = app.Joint2AngleKnob.Value*(pi/180);
            
            qa = [app.angles(1,1); app.angles(2,1); app.angles(3,1)];
            
            try
                [app.footPose,qu] = geomFK(app,qa,app.lengths,1);
                
                app.angles = [qa(1), qu(1), qu(2);
                      qa(2), qu(3), qu(4);
                      qa(3), qu(5), qu(6)];
                
                app.Joint1AngleKnob.Value = app.angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = app.angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = app.angles(3,1)*(180/pi);
                app.EditField.Value = app.angles(1,1)*(180/pi);
                app.EditField_2.Value = app.angles(2,1)*(180/pi);
                app.EditField_3.Value = app.angles(3,1)*(180/pi);
                
                app.FootXPosKnob.Value = app.footPose(1);
                app.FootYPosKnob.Value = app.footPose(2);
                app.FootAngleKnob.Value = app.footPose(3)*(180/pi);
                app.EditField_4.Value = app.footPose(1);
                app.EditField_5.Value = app.footPose(2);
                app.EditField_6.Value = app.footPose(3)*(180/pi);
                
                plotRobot(app, app.footPose, app.lengths, app.angles);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.Joint2AngleKnob.Value = app.EditField_2.Value;
            app.angles(2,1) = app.EditField_2.Value*(pi/180);
            
            qa = [app.angles(1,1); app.angles(2,1); app.angles(3,1)];
            
            try
                [app.footPose,qu] = geomFK(app,qa,app.lengths,1);
                
                app.angles = [qa(1), qu(1), qu(2);
                      qa(2), qu(3), qu(4);
                      qa(3), qu(5), qu(6)];
                
                app.Joint1AngleKnob.Value = app.angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = app.angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = app.angles(3,1)*(180/pi);
                app.EditField.Value = app.angles(1,1)*(180/pi);
                app.EditField_2.Value = app.angles(2,1)*(180/pi);
                app.EditField_3.Value = app.angles(3,1)*(180/pi);
                
                app.FootXPosKnob.Value = app.footPose(1);
                app.FootYPosKnob.Value = app.footPose(2);
                app.FootAngleKnob.Value = app.footPose(3)*(180/pi);
                app.EditField_4.Value = app.footPose(1);
                app.EditField_5.Value = app.footPose(2);
                app.EditField_6.Value = app.footPose(3)*(180/pi);
                
                plotRobot(app, app.footPose, app.lengths, app.angles);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
        end

        % Value changed function: Joint3AngleKnob
        function Joint3AngleKnobValueChanged(app, event)
            app.EditField_3.Value = app.Joint3AngleKnob.Value;
            app.angles(3,1) = app.Joint3AngleKnob.Value*(pi/180);
            
            qa = [app.angles(1,1); app.angles(2,1); app.angles(3,1)];
            
            try
                [app.footPose,qu] = geomFK(app,qa,app.lengths,1);
                
                app.angles = [qa(1), qu(1), qu(2);
                      qa(2), qu(3), qu(4);
                      qa(3), qu(5), qu(6)];
                
                app.Joint1AngleKnob.Value = app.angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = app.angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = app.angles(3,1)*(180/pi);
                app.EditField.Value = app.angles(1,1)*(180/pi);
                app.EditField_2.Value = app.angles(2,1)*(180/pi);
                app.EditField_3.Value = app.angles(3,1)*(180/pi);
                
                app.FootXPosKnob.Value = app.footPose(1);
                app.FootYPosKnob.Value = app.footPose(2);
                app.FootAngleKnob.Value = app.footPose(3)*(180/pi);
                app.EditField_4.Value = app.footPose(1);
                app.EditField_5.Value = app.footPose(2);
                app.EditField_6.Value = app.footPose(3)*(180/pi);
                
                plotRobot(app, app.footPose, app.lengths, app.angles);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            app.Joint3AngleKnob.Value = app.EditField_3.Value;
            app.angles(3,1) = app.EditField_3.Value*(pi/180);
            
            qa = [app.angles(1,1); app.angles(2,1); app.angles(3,1)];
            
            try
                [app.footPose,qu] = geomFK(app,qa,app.lengths,1);
                
                app.angles = [qa(1), qu(1), qu(2);
                      qa(2), qu(3), qu(4);
                      qa(3), qu(5), qu(6)];
                
                app.Joint1AngleKnob.Value = app.angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = app.angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = app.angles(3,1)*(180/pi);
                app.EditField.Value = app.angles(1,1)*(180/pi);
                app.EditField_2.Value = app.angles(2,1)*(180/pi);
                app.EditField_3.Value = app.angles(3,1)*(180/pi);
                
                app.FootXPosKnob.Value = app.footPose(1);
                app.FootYPosKnob.Value = app.footPose(2);
                app.FootAngleKnob.Value = app.footPose(3)*(180/pi);
                app.EditField_4.Value = app.footPose(1);
                app.EditField_5.Value = app.footPose(2);
                app.EditField_6.Value = app.footPose(3)*(180/pi);
                
                plotRobot(app, app.footPose, app.lengths, app.angles);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
        end

        % Value changed function: FootXPosKnob
        function FootXPosKnobValueChanged(app, event)
            app.EditField_4.Value = app.FootXPosKnob.Value;
            app.footPose(1) = app.FootXPosKnob.Value;
            
            try
                [angles] = subchainIK(app,app.footPose,app.lengths,1);
                app.angles = angles;
                
                app.Joint1AngleKnob.Value = angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = angles(3,1)*(180/pi);
                app.EditField.Value = angles(1,1)*(180/pi);
                app.EditField_2.Value = angles(2,1)*(180/pi);
                app.EditField_3.Value = angles(3,1)*(180/pi);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            app.FootXPosKnob.Value = app.EditField_4.Value;
            app.footPose(1) = app.EditField_4.Value;
            
            try
                [angles] = subchainIK(app,app.footPose,app.lengths,1);
                app.angles = angles;
                
                app.Joint1AngleKnob.Value = angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = angles(3,1)*(180/pi);
                app.EditField.Value = angles(1,1)*(180/pi);
                app.EditField_2.Value = angles(2,1)*(180/pi);
                app.EditField_3.Value = angles(3,1)*(180/pi);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
        end

        % Value changed function: FootYPosKnob
        function FootYPosKnobValueChanged(app, event)
            app.EditField_5.Value = app.FootYPosKnob.Value;
            app.footPose(2) = app.FootYPosKnob.Value;
            
            try
                [angles] = subchainIK(app,app.footPose,app.lengths,1);
                app.angles = angles;
                
                app.Joint1AngleKnob.Value = angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = angles(3,1)*(180/pi);
                app.EditField.Value = angles(1,1)*(180/pi);
                app.EditField_2.Value = angles(2,1)*(180/pi);
                app.EditField_3.Value = angles(3,1)*(180/pi);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            app.FootYPosKnob.Value = app.EditField_5.Value;
            app.footPose(2) = app.EditField_5.Value;
            
            try
                [angles] = subchainIK(app,app.footPose,app.lengths,1);
                app.angles = angles;
                
                app.Joint1AngleKnob.Value = angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = angles(3,1)*(180/pi);
                app.EditField.Value = angles(1,1)*(180/pi);
                app.EditField_2.Value = angles(2,1)*(180/pi);
                app.EditField_3.Value = angles(3,1)*(180/pi);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
        end

        % Value changed function: FootAngleKnob
        function FootAngleKnobValueChanged(app, event)
            app.EditField_6.Value = app.FootAngleKnob.Value;
            app.footPose(3) = app.FootAngleKnob.Value*(pi/180);
            
            try
                [angles] = subchainIK(app,app.footPose,app.lengths,1);
                app.angles = angles;
                
                app.Joint1AngleKnob.Value = angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = angles(3,1)*(180/pi);
                app.EditField.Value = angles(1,1)*(180/pi);
                app.EditField_2.Value = angles(2,1)*(180/pi);
                app.EditField_3.Value = angles(3,1)*(180/pi);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            app.FootAngleKnob.Value = app.EditField_6.Value;
            app.footPose(3) = app.EditField_6.Value*pi/180;
            
            try
                [angles] = subchainIK(app,app.footPose,app.lengths,1);
                app.angles = angles;
                
                app.Joint1AngleKnob.Value = angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = angles(3,1)*(180/pi);
                app.EditField.Value = angles(1,1)*(180/pi);
                app.EditField_2.Value = angles(2,1)*(180/pi);
                app.EditField_3.Value = angles(3,1)*(180/pi);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
        end

        % Button pushed function: ResetConfigButton
        function ResetConfigButtonPushed(app, event)
            app.footPoseDeg = app.defaultFootPoseDeg;
            app.footPose = app.footPoseDeg;
            app.footPose(3) = app.footPoseDeg(3)*pi/180;
            
            app.FootXPosKnob.Value = app.footPoseDeg(1);
            app.FootYPosKnob.Value = app.footPoseDeg(2);
            app.FootAngleKnob.Value = app.footPoseDeg(3);
            app.EditField_4.Value = app.footPoseDeg(1);
            app.EditField_5.Value = app.footPoseDeg(2);
            app.EditField_6.Value = app.footPoseDeg(3);
            
            try
                [angles] = subchainIK(app,app.footPose,app.lengths,1);
                app.angles = angles;
                
                app.Joint1AngleKnob.Value = angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = angles(3,1)*(180/pi);
                app.EditField.Value = angles(1,1)*(180/pi);
                app.EditField_2.Value = angles(2,1)*(180/pi);
                app.EditField_3.Value = angles(3,1)*(180/pi);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
        end

        % Button pushed function: ResetLengthsButton
        function ResetLengthsButtonPushed(app, event)
            app.lengths = app.defaultLengths;
            
            app.Link1Slider.Value = app.lengths(1);
            app.Link2Slider.Value = app.lengths(2);
            app.Link3Slider.Value = app.lengths(3);
            app.Link4Slider.Value = app.lengths(4);
            app.Link5Slider.Value = app.lengths(5);
            app.Link6Slider.Value = app.lengths(6);
            app.Link7Slider.Value = app.lengths(7);
            app.Link8Slider.Value = app.lengths(8);
            app.LR_MxSlider.Value = app.lengths(9);
            app.LR_MySlider.Value = app.lengths(11);
            
            app.EditField_7.Value = app.lengths(1);
            app.EditField_8.Value = app.lengths(2);
            app.EditField_9.Value = app.lengths(3);
            app.EditField_10.Value = app.lengths(4);
            app.EditField_11.Value = app.lengths(5);
            app.EditField_12.Value = app.lengths(6);
            app.EditField_13.Value = app.lengths(7);
            app.EditField_14.Value = app.lengths(8);
            app.EditField_15.Value = app.lengths(9);
            app.EditField_16.Value = app.lengths(11);
            
            app.FootXPosKnob.Value = app.footPoseDeg(1);
            app.FootYPosKnob.Value = app.footPoseDeg(2);
            app.FootAngleKnob.Value = app.footPoseDeg(3);
            app.EditField_4.Value = app.footPoseDeg(1);
            app.EditField_5.Value = app.footPoseDeg(2);
            app.EditField_6.Value = app.footPoseDeg(3);
            
            try
                [angles] = subchainIK(app,app.footPose,app.lengths,1);
                app.angles = angles;
                
                app.Joint1AngleKnob.Value = angles(1,1)*(180/pi);
                app.Joint2AngleKnob.Value = angles(2,1)*(180/pi);
                app.Joint3AngleKnob.Value = angles(3,1)*(180/pi);
                app.EditField.Value = angles(1,1)*(180/pi);
                app.EditField_2.Value = angles(2,1)*(180/pi);
                app.EditField_3.Value = angles(3,1)*(180/pi);
                
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'on';
                app.UnsolvableLamp.Enable = 'off';
            catch
                app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
                app.UnsolvableLamp.Enable = 'on';
            end
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
            
        end

        % Value changed function: FyEditField
        function FyEditFieldValueChanged(app, event)
            value = app.FyEditField.Value;
            app.wrench(2) = value;
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
        end

        % Value changed function: FxEditField
        function FxEditFieldValueChanged(app, event)
            value = app.FxEditField.Value;
            app.wrench(1) = value;
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
        end

        % Value changed function: TzEditField
        function TzEditFieldValueChanged(app, event)
            value = app.TzEditField.Value;
            app.wrench(3) = value;
            
            try
               [torques] = wrench2Torques(app,app.angles,app.lengths,app.wrench);
               app.torques = torques;
               app.T1EditField.Value = app.torques(1);
               app.T2EditField.Value = app.torques(2);
               app.T3EditField.Value = app.torques(3);
            catch
                fprintf("torque calculation failed.\n")
            end
        end

        % Button pushed function: SENDITButton
        function SENDITButtonPushed(app, event)
            port='/dev/ttyUSB0';
            if ~isempty(instrfind)
            fclose(instrfind);
            delete(instrfind);
            end
            
            fprintf('Opening port  %s....\n',port);
            mySerial = serial(port, 'BaudRate', 115200,'Timeout',10); 
            
            fopen(mySerial);
            fprintf('serial port opened!\n');
            nsamples = fscanf(mySerial,'%d');
            
            fprintf('Expecting %d samples\n',nsamples);
            
            runPermission = 1;
            fprintf(mySerial,'%d\n',runPermission);
            
            %     Amp = input('\nEnter amplitude: ');
            %     
            % 
            %     
            %     f = input('\nEnter frequency: ');
            % 
            %     ts = 1/500;
            %     T = ts*nsamples;
            %     t = 0:ts:T;
            %     y_mA = int16(Amp*1000*sin(2*pi*f*t));
            %     
            %     figure;
            %     plot(t,y_mA)
            
            %     qa1_fullext = -1.6845*(180/pi)*10;
            %     qa2_fullext = -2.6214*(180/pi)*10;
            %     qa3_fullext = -1.4571*(180/pi)*10;
            %     
            %     qa1_fullcmp = -2.6564*(180/pi)*10;
            %     qa2_fullcmp = -2.9706*(180/pi)*10;
            %     qa3_fullcmp = -0.4835*(180/pi)*10;
            
            qa1_fullext = -1800;
            qa2_fullext = -1800;
            qa3_fullext = -1800;
            
            qa1_fullcmp = 0;
            qa2_fullcmp = 0;
            qa3_fullcmp = 0;
            
            qa(:,1:3) = [linspace(qa1_fullext,qa1_fullcmp,nsamples)', ...
            linspace(qa2_fullext,qa2_fullcmp,nsamples)', ...
            linspace(qa3_fullext,qa3_fullcmp,nsamples)'];
            
            
%             for i=1:length(y_mA)-1
%             str = sprintf('%d',y_mA(i));
%             fprintf('sending  y(%d) = %d\n',i,y_mA(i))
%             fprintf(mySerial,'%s\n',str);
%             end
            
            %      for i=1:nsamples
            %         str = sprintf('%5.3f %5.3f %5.3f',qa(i,1),qa(i,2),qa(i,3));
            %         fprintf('sending  qa(%d) = %5.3f %5.3f %5.3f\n',i,qa(i,1),qa(i,2),qa(i,3))
            %         fprintf(mySerial,'%s\n',str);
            %      end
            
            %      fprintf("done sending\n");
            %      flushinput(mySerial);
            %      fscanf(mySerial); % flush the serial buffer
            
            
            %      ser_check = fscanf(mySerial,'%d');
            %      fprintf("ser_check = %d\n",ser_check);
            %      
            %      if (fscanf(mySerial,'%d') ~= nsamples)
            %         writePermission = 0;
            %         fprintf(mySerial,'%d\n',writePermission);
            %         fprintf("Serial comm experienced an error. Program ending.\n"); 
            %         return;
            %      end
            
            fscanf(mySerial);
            
            %      flushoutput(mySerial);
            flushinput(mySerial); % flush the serial buffer
            
            %     
            %         
            writePermission = 1;
            fprintf(mySerial,'%d\n',writePermission);
            %     
            %     fprintf(mySerial,'1\n');
            %     data = zeros(nsamples); 
            %     
            % 
            fscanf(mySerial);
            for i=1:nsamples
            %           str = fscanf(mySerial,'%s');
            %           fprintf('%s\n',str);
            data(i,1:3) = fscanf(mySerial,'%f %f %f');
            fprintf('%d: %f %f %f\n',[i-1,data(i,1:3)]);
            end
            
            fprintf("done reading\n");
            
            figure;
            plot(data(:,1:3));
            legend('\theta_1','\phi_1','\psi_1','Location','Best');
            title('Received data')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlanarHopperVisualizerUIFigure = uifigure('Visible', 'off');
            app.PlanarHopperVisualizerUIFigure.Name = 'Planar Hopper Visualizer';
            app.UIAxes = uiaxes(app.PlanarHopperVisualizerUIFigure);
            title(app.UIAxes, 'Robot Configuration')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-0.25 0.25];
            app.UIAxes.YLim = [-0.35 0.15];
            app.TabGroup = uitabgroup(app.PlanarHopperVisualizerUIFigure);
            app.ForwardKinematicsTab = uitab(app.TabGroup);
            app.ForwardKinematicsTab.Title = 'Forward Kinematics';
            app.Joint1AngleKnobLabel = uilabel(app.ForwardKinematicsTab);
            app.Joint1AngleKnobLabel.Text = 'Joint 1 Angle';
            app.Joint1AngleKnob = uiknob(app.ForwardKinematicsTab, 'continuous');
            app.Joint1AngleKnob.Limits = [-180 180];
            app.Joint1AngleKnob.ValueChangedFcn = createCallbackFcn(app, @Joint1AngleKnobValueChanged, true);
            app.Joint2AngleKnobLabel = uilabel(app.ForwardKinematicsTab);
            app.Joint2AngleKnobLabel.Text = 'Joint 2 Angle';
            app.Joint2AngleKnob = uiknob(app.ForwardKinematicsTab, 'continuous');
            app.Joint2AngleKnob.Limits = [-180 180];
            app.Joint2AngleKnob.ValueChangedFcn = createCallbackFcn(app, @Joint2AngleKnobValueChanged, true);
            app.EditField = uieditfield(app.ForwardKinematicsTab, 'numeric');
            app.EditField.Limits = [-180 180];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.ForwardKinematicsTab, 'numeric');
            app.EditField_2.Limits = [-180 180];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.Joint3AngleKnobLabel = uilabel(app.ForwardKinematicsTab);
            app.Joint3AngleKnobLabel.Text = 'Joint 3 Angle';
            app.Joint3AngleKnob = uiknob(app.ForwardKinematicsTab, 'continuous');
            app.Joint3AngleKnob.Limits = [-180 180];
            app.Joint3AngleKnob.ValueChangedFcn = createCallbackFcn(app, @Joint3AngleKnobValueChanged, true);
            app.EditField_3 = uieditfield(app.ForwardKinematicsTab, 'numeric');
            app.EditField_3.Limits = [-180 180];
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.InverseKinematicsTab = uitab(app.TabGroup);
            app.InverseKinematicsTab.Title = 'Inverse Kinematics';
            app.FootXPosKnobLabel = uilabel(app.InverseKinematicsTab);
            app.FootXPosKnobLabel.Text = 'Foot X Pos';
            app.FootXPosKnob = uiknob(app.InverseKinematicsTab, 'continuous');
            app.FootXPosKnob.Limits = [-0.25 0.25];
            app.FootXPosKnob.ValueChangedFcn = createCallbackFcn(app, @FootXPosKnobValueChanged, true);
            app.FootYPosKnobLabel = uilabel(app.InverseKinematicsTab);
            app.FootYPosKnobLabel.Text = 'Foot Y Pos';
            app.FootYPosKnob = uiknob(app.InverseKinematicsTab, 'continuous');
            app.FootYPosKnob.Limits = [-0.5 0];
            app.FootYPosKnob.ValueChangedFcn = createCallbackFcn(app, @FootYPosKnobValueChanged, true);
            app.FootAngleKnobLabel = uilabel(app.InverseKinematicsTab);
            app.FootAngleKnobLabel.Text = 'Foot Angle';
            app.FootAngleKnob = uiknob(app.InverseKinematicsTab, 'continuous');
            app.FootAngleKnob.Limits = [-135 -45];
            app.FootAngleKnob.ValueChangedFcn = createCallbackFcn(app, @FootAngleKnobValueChanged, true);
            app.FootAngleKnob.Value = -45;
            app.EditField_4 = uieditfield(app.InverseKinematicsTab, 'numeric');
            app.EditField_4.Limits = [-0.5 0.5];
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_5 = uieditfield(app.InverseKinematicsTab, 'numeric');
            app.EditField_5.Limits = [-0.5 0];
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_6 = uieditfield(app.InverseKinematicsTab, 'numeric');
            app.EditField_6.Limits = [-135 -45];
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_6.Value = -45;
            app.ForceTorqueTab = uitab(app.TabGroup);
            app.ForceTorqueTab.Title = 'Force - Torque';
            app.SwitchLabel = uilabel(app.ForceTorqueTab);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.ForceTorqueTab, 'toggle');
            app.Switch.Items = {'T2F', 'F2T'};
            app.Switch.Value = 'F2T';
            app.FxEditFieldLabel = uilabel(app.ForceTorqueTab);
            app.FxEditFieldLabel.Text = 'Fx';
            app.FxEditField = uieditfield(app.ForceTorqueTab, 'numeric');
            app.FxEditField.Limits = [-250 250];
            app.FxEditField.ValueChangedFcn = createCallbackFcn(app, @FxEditFieldValueChanged, true);
            app.FyEditFieldLabel = uilabel(app.ForceTorqueTab);
            app.FyEditFieldLabel.Text = 'Fy';
            app.FyEditField = uieditfield(app.ForceTorqueTab, 'numeric');
            app.FyEditField.Limits = [-250 250];
            app.FyEditField.ValueChangedFcn = createCallbackFcn(app, @FyEditFieldValueChanged, true);
            app.TzEditFieldLabel = uilabel(app.ForceTorqueTab);
            app.TzEditFieldLabel.Text = 'Tz';
            app.TzEditField = uieditfield(app.ForceTorqueTab, 'numeric');
            app.TzEditField.ValueChangedFcn = createCallbackFcn(app, @TzEditFieldValueChanged, true);
            app.T1EditFieldLabel = uilabel(app.ForceTorqueTab);
            app.T1EditFieldLabel.Text = 'T1';
            app.T1EditField = uieditfield(app.ForceTorqueTab, 'numeric');
            app.T2EditFieldLabel = uilabel(app.ForceTorqueTab);
            app.T2EditFieldLabel.Text = 'T2';
            app.T2EditField = uieditfield(app.ForceTorqueTab, 'numeric');
            app.T3EditFieldLabel = uilabel(app.ForceTorqueTab);
            app.T3EditFieldLabel.Text = 'T3';
            app.T3EditField = uieditfield(app.ForceTorqueTab, 'numeric');
            app.ConfigurationStatusPanel = uipanel(app.PlanarHopperVisualizerUIFigure);
            app.ConfigurationStatusPanel.Title = 'Configuration Status';
            app.SolvableandsatisfiesjointlimitsLampLabel = uilabel(app.ConfigurationStatusPanel);
            app.SolvableandsatisfiesjointlimitsLampLabel.Text = 'Solvable and satisfies joint limits';
            app.SolvableandsatisfiesjointlimitsLamp = uilamp(app.ConfigurationStatusPanel);
            app.SolvableandsatisfiesjointlimitsLamp.Enable = 'off';
            app.SolvablebutlinkscollideLampLabel = uilabel(app.ConfigurationStatusPanel);
            app.SolvablebutlinkscollideLampLabel.Text = 'Solvable but links collide';
            app.SolvablebutlinkscollideLamp = uilamp(app.ConfigurationStatusPanel);
            app.SolvablebutlinkscollideLamp.Enable = 'off';
            app.SolvablebutviolatesjointlimitsLampLabel = uilabel(app.ConfigurationStatusPanel);
            app.SolvablebutviolatesjointlimitsLampLabel.Text = 'Solvable but violates joint limits';
            app.SolvablebutviolatesjointlimitsLamp = uilamp(app.ConfigurationStatusPanel);
            app.SolvablebutviolatesjointlimitsLamp.Enable = 'off';
            app.UnsolvableLampLabel = uilabel(app.ConfigurationStatusPanel);
            app.UnsolvableLampLabel.Text = 'Unsolvable';
            app.UnsolvableLamp = uilamp(app.ConfigurationStatusPanel);
            app.UnsolvableLamp.Enable = 'off';
            app.EditLinkLengthsPanel = uipanel(app.PlanarHopperVisualizerUIFigure);
            app.EditLinkLengthsPanel.Title = 'Edit Link Lengths';
            app.Link1SliderLabel = uilabel(app.EditLinkLengthsPanel);
            app.Link1SliderLabel.Text = 'Link 1';
            app.Link1Slider = uislider(app.EditLinkLengthsPanel);
            app.Link1Slider.Limits = [0 0.2];
            app.Link1Slider.ValueChangedFcn = createCallbackFcn(app, @Link1SliderValueChanged, true);
            app.Link2SliderLabel = uilabel(app.EditLinkLengthsPanel);
            app.Link2SliderLabel.Text = 'Link 2';
            app.Link2Slider = uislider(app.EditLinkLengthsPanel);
            app.Link2Slider.Limits = [0 0.2];
            app.Link2Slider.ValueChangedFcn = createCallbackFcn(app, @Link2SliderValueChanged, true);
            app.Link3SliderLabel = uilabel(app.EditLinkLengthsPanel);
            app.Link3SliderLabel.Text = 'Link 3';
            app.Link3Slider = uislider(app.EditLinkLengthsPanel);
            app.Link3Slider.Limits = [0 0.2];
            app.Link3Slider.ValueChangedFcn = createCallbackFcn(app, @Link3SliderValueChanged, true);
            app.Link4SliderLabel = uilabel(app.EditLinkLengthsPanel);
            app.Link4SliderLabel.Text = 'Link 4';
            app.Link4Slider = uislider(app.EditLinkLengthsPanel);
            app.Link4Slider.Limits = [0 0.2];
            app.Link4Slider.ValueChangedFcn = createCallbackFcn(app, @Link4SliderValueChanged, true);
            app.Link5SliderLabel = uilabel(app.EditLinkLengthsPanel);
            app.Link5SliderLabel.Text = 'Link 5';
            app.Link5Slider = uislider(app.EditLinkLengthsPanel);
            app.Link5Slider.Limits = [0 0.2];
            app.Link5Slider.ValueChangedFcn = createCallbackFcn(app, @Link5SliderValueChanged, true);
            app.Link6SliderLabel = uilabel(app.EditLinkLengthsPanel);
            app.Link6SliderLabel.Text = 'Link 6';
            app.Link6Slider = uislider(app.EditLinkLengthsPanel);
            app.Link6Slider.Limits = [0 0.2];
            app.Link6Slider.ValueChangedFcn = createCallbackFcn(app, @Link6SliderValueChanged, true);
            app.Link7SliderLabel = uilabel(app.EditLinkLengthsPanel);
            app.Link7SliderLabel.Text = 'Link 7';
            app.Link7Slider = uislider(app.EditLinkLengthsPanel);
            app.Link7Slider.Limits = [0 0.2];
            app.Link7Slider.ValueChangedFcn = createCallbackFcn(app, @Link7SliderValueChanged, true);
            app.Link8SliderLabel = uilabel(app.EditLinkLengthsPanel);
            app.Link8SliderLabel.Text = 'Link 8';
            app.Link8Slider = uislider(app.EditLinkLengthsPanel);
            app.Link8Slider.Limits = [0 0.2];
            app.Link8Slider.ValueChangedFcn = createCallbackFcn(app, @Link8SliderValueChanged, true);
            app.LR_MxSliderLabel = uilabel(app.EditLinkLengthsPanel);
            app.LR_MxSliderLabel.Text = 'LR_Mx';
            app.LR_MxSlider = uislider(app.EditLinkLengthsPanel);
            app.LR_MxSlider.Limits = [0 0.2];
            app.LR_MxSlider.ValueChangedFcn = createCallbackFcn(app, @LR_MxSliderValueChanged, true);
            app.LR_MySliderLabel = uilabel(app.EditLinkLengthsPanel);
            app.LR_MySliderLabel.Text = 'LR_My';
            app.LR_MySlider = uislider(app.EditLinkLengthsPanel);
            app.LR_MySlider.Limits = [-0.2 0.2];
            app.LR_MySlider.ValueChangedFcn = createCallbackFcn(app, @LR_MySliderValueChanged, true);
            app.EditField_7 = uieditfield(app.EditLinkLengthsPanel, 'numeric');
            app.EditField_7.Limits = [0 0.2];
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.EditField_8 = uieditfield(app.EditLinkLengthsPanel, 'numeric');
            app.EditField_8.Limits = [0 0.2];
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.EditField_9 = uieditfield(app.EditLinkLengthsPanel, 'numeric');
            app.EditField_9.Limits = [0 0.2];
            app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @EditField_9ValueChanged, true);
            app.EditField_10 = uieditfield(app.EditLinkLengthsPanel, 'numeric');
            app.EditField_10.Limits = [0 0.2];
            app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @EditField_10ValueChanged, true);
            app.EditField_11 = uieditfield(app.EditLinkLengthsPanel, 'numeric');
            app.EditField_11.Limits = [0 0.2];
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.EditField_12 = uieditfield(app.EditLinkLengthsPanel, 'numeric');
            app.EditField_12.Limits = [0 0.2];
            app.EditField_12.ValueChangedFcn = createCallbackFcn(app, @EditField_12ValueChanged, true);
            app.EditField_13 = uieditfield(app.EditLinkLengthsPanel, 'numeric');
            app.EditField_13.Limits = [0 0.2];
            app.EditField_13.ValueChangedFcn = createCallbackFcn(app, @EditField_13ValueChanged, true);
            app.EditField_14 = uieditfield(app.EditLinkLengthsPanel, 'numeric');
            app.EditField_14.Limits = [0 0.2];
            app.EditField_14.ValueChangedFcn = createCallbackFcn(app, @EditField_14ValueChanged, true);
            app.EditField_15 = uieditfield(app.EditLinkLengthsPanel, 'numeric');
            app.EditField_15.Limits = [0 0.2];
            app.EditField_15.ValueChangedFcn = createCallbackFcn(app, @EditField_15ValueChanged, true);
            app.EditField_16 = uieditfield(app.EditLinkLengthsPanel, 'numeric');
            app.EditField_16.Limits = [-0.2 0.2];
            app.EditField_16.ValueChangedFcn = createCallbackFcn(app, @EditField_16ValueChanged, true);
            app.HelpButton = uibutton(app.PlanarHopperVisualizerUIFigure, 'push');
            app.HelpButton.Text = 'Help';
            app.AboutButton = uibutton(app.PlanarHopperVisualizerUIFigure, 'push');
            app.AboutButton.Text = 'About';
            app.SENDITButton = uibutton(app.PlanarHopperVisualizerUIFigure, 'push');
            app.SENDITButton.ButtonPushedFcn = createCallbackFcn(app, @SENDITButtonPushed, true);
            app.SENDITButton.Text = 'SEND IT';
            app.ResetLengthsButton = uibutton(app.PlanarHopperVisualizerUIFigure, 'push');
            app.ResetLengthsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetLengthsButtonPushed, true);
            app.ResetLengthsButton.Text = 'Reset Lengths';
            app.ResetConfigButton = uibutton(app.PlanarHopperVisualizerUIFigure, 'push');
            app.ResetConfigButton.ButtonPushedFcn = createCallbackFcn(app, @ResetConfigButtonPushed, true);
            app.ResetConfigButton.Text = 'Reset Config.';
            % Show the figure after all components are created
            app.PlanarHopperVisualizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotKinematics_v0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlanarHopperVisualizerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlanarHopperVisualizerUIFigure)
        end
    end
end