classdef merlet_ui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        ReadMeTab              matlab.ui.container.Tab
        TextArea               matlab.ui.control.TextArea
        StaticTestLabel        matlab.ui.control.Label
        TextArea_2             matlab.ui.control.TextArea
        WaypointTestLabel      matlab.ui.control.Label
        TextArea_3             matlab.ui.control.TextArea
        DynamicTestLabel       matlab.ui.control.Label
        TextArea_4             matlab.ui.control.TextArea
        StaticTab              matlab.ui.container.Tab
        XposSliderLabel        matlab.ui.control.Label
        XposSlider             matlab.ui.control.Slider
        YposSliderLabel        matlab.ui.control.Label
        YposSlider             matlab.ui.control.Slider
        ZposSliderLabel        matlab.ui.control.Label
        ZposSlider             matlab.ui.control.Slider
        RollSliderLabel        matlab.ui.control.Label
        RollSlider             matlab.ui.control.Slider
        PitchSliderLabel       matlab.ui.control.Label
        PitchSlider            matlab.ui.control.Slider
        YawSliderLabel         matlab.ui.control.Label
        YawSlider              matlab.ui.control.Slider
        t1SliderLabel          matlab.ui.control.Label
        t1Slider               matlab.ui.control.Slider
        t2SliderLabel          matlab.ui.control.Label
        t2Slider               matlab.ui.control.Slider
        XposEditField          matlab.ui.control.NumericEditField
        YposEditField          matlab.ui.control.NumericEditField
        ZposEditField          matlab.ui.control.NumericEditField
        RollEditField          matlab.ui.control.NumericEditField
        PitchEditField         matlab.ui.control.NumericEditField
        YawEditField           matlab.ui.control.NumericEditField
        t1EditField            matlab.ui.control.NumericEditField
        t2EditField            matlab.ui.control.NumericEditField
        ButtonGroup_2          matlab.ui.container.ButtonGroup
        PrepareTestButton_2    matlab.ui.control.ToggleButton
        CancelTestButton_2     matlab.ui.control.ToggleButton
        BeginTestButton_4      matlab.ui.control.ToggleButton
        HomeButton             matlab.ui.control.Button
        WaypointTab            matlab.ui.container.Tab
        ButtonGroup_3          matlab.ui.container.ButtonGroup
        PrepareTestButton_3    matlab.ui.control.ToggleButton
        BeginTestButton_3      matlab.ui.control.ToggleButton
        CancelTestButton_3     matlab.ui.control.ToggleButton
        XposSlider_2Label      matlab.ui.control.Label
        XposSlider_2           matlab.ui.control.Slider
        YposSlider_2Label      matlab.ui.control.Label
        YposSlider_2           matlab.ui.control.Slider
        ZposSlider_2Label      matlab.ui.control.Label
        ZposSlider_2           matlab.ui.control.Slider
        RollSlider_2Label      matlab.ui.control.Label
        RollSlider_2           matlab.ui.control.Slider
        PitchSlider_2Label     matlab.ui.control.Label
        PitchSlider_2          matlab.ui.control.Slider
        YawSlider_2Label       matlab.ui.control.Label
        YawSlider_2            matlab.ui.control.Slider
        t1Slider_2Label        matlab.ui.control.Label
        t1Slider_2             matlab.ui.control.Slider
        t2Slider_2Label        matlab.ui.control.Label
        t2Slider_2             matlab.ui.control.Slider
        XposEditField_2        matlab.ui.control.NumericEditField
        YposEditField_2        matlab.ui.control.NumericEditField
        ZposEditField_2        matlab.ui.control.NumericEditField
        RollEditField_4        matlab.ui.control.NumericEditField
        PitchEditField_4       matlab.ui.control.NumericEditField
        YawEditField_4         matlab.ui.control.NumericEditField
        t1EditField_2          matlab.ui.control.NumericEditField
        t2EditField_2          matlab.ui.control.NumericEditField
        DynamicTab             matlab.ui.container.Tab
        XEditFieldLabel        matlab.ui.control.Label
        XEditField             matlab.ui.control.NumericEditField
        YEditFieldLabel        matlab.ui.control.Label
        YEditField             matlab.ui.control.NumericEditField
        ZEditFieldLabel        matlab.ui.control.Label
        ZEditField             matlab.ui.control.NumericEditField
        RollEditField_2Label   matlab.ui.control.Label
        RollEditField_2        matlab.ui.control.NumericEditField
        PitchEditField_2Label  matlab.ui.control.Label
        PitchEditField_2       matlab.ui.control.NumericEditField
        YawEditField_2Label    matlab.ui.control.Label
        YawEditField_2         matlab.ui.control.NumericEditField
        sinLabel               matlab.ui.control.Label
        sinLabel_2             matlab.ui.control.Label
        sinLabel_3             matlab.ui.control.Label
        sinLabel_4             matlab.ui.control.Label
        sinLabel_5             matlab.ui.control.Label
        sinLabel_6             matlab.ui.control.Label
        XEditField_2           matlab.ui.control.NumericEditField
        YEditField_2           matlab.ui.control.NumericEditField
        ZEditField_2           matlab.ui.control.NumericEditField
        RollEditField_3        matlab.ui.control.NumericEditField
        PitchEditField_3       matlab.ui.control.NumericEditField
        YawEditField_3         matlab.ui.control.NumericEditField
        tLabel                 matlab.ui.control.Label
        tLabel_2               matlab.ui.control.Label
        tLabel_3               matlab.ui.control.Label
        tLabel_4               matlab.ui.control.Label
        tLabel_5               matlab.ui.control.Label
        tLabel_6               matlab.ui.control.Label
        ButtonGroup            matlab.ui.container.ButtonGroup
        PrepareTestButton      matlab.ui.control.ToggleButton
        BeginTestButton        matlab.ui.control.ToggleButton
        CancelTestButton       matlab.ui.control.ToggleButton
        CalibrateTab           matlab.ui.container.Tab
    end

    
    properties (Access = public)
         hello = 7;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RollSlider
        function rollValueChanged(app, event)
            value = app.RollSlider.Value;
            app.RollEditField.Value = value;
            assignin('base', "rollValue", floor(value * 1000));
            
        end

        % Value changed function: PitchSlider
        function pitchValueChanged(app, event)
            value = app.PitchSlider.Value;
            app.PitchEditField.Value = value;
            assignin('base', "pitchValue", floor(value * 1000));
        end

        % Value changed function: YawSlider
        function yawValueChanged(app, event)
            value = app.YawSlider.Value;
            app.YawEditField.Value = value;   
            assignin('base', "yawValue", floor(value * 1000));
        end

        % Value changed function: XposSlider
        function xValueChanged(app, event)
            value = app.XposSlider.Value;
            app.XposEditField.Value = value;
            assignin('base', "XPosValue", floor(value * 1000));
            
        end

        % Value changed function: YposSlider
        function yValueChanged(app, event)
            value = app.YposSlider.Value;
            app.YposEditField.Value = value;
            assignin('base', "YPosValue", floor(value * 1000));
        end

        % Value changed function: ZposSlider
        function zValueChanged(app, event)
            value = app.ZposSlider.Value;
            app.ZposEditField.Value = value;
            assignin('base', "ZPosValue", floor(value * 1000));
        end

        % Value changed function: t1Slider
        function t1SliderValueChanged(app, event)
            value = app.t1Slider.Value;
            app.t1EditField.Value = value;
            
            assignin('base', "XPosValue", value);
            app.XposSlider.Value = value;
            app.XposEditField.Value = value;
            assignin('base', "YPosValue", value);
            app.YposSlider.Value = value;
            app.YposEditField.Value = value;
            assignin('base', "ZPosValue", value);
            app.ZposSlider.Value = value;
            app.ZposEditField.Value = value;
            assignin('base', "yawValue", value);
            app.YawSlider.Value = value;
            app.YawEditField.Value = value;
            assignin('base', "rollValue", value);
            app.RollSlider.Value = value;
            app.RollEditField.Value = value;
            assignin('base', "pitchValue", value);
            app.PitchSlider.Value = value;
            app.PitchEditField.Value = value;
            
        end

        % Value changed function: t2Slider
        function t2SliderValueChanged(app, event)
            value = app.t2Slider.Value;
            app.t2EditField.Value = value;
            
        end

        % Value changed function: XposEditField
        function XposEditFieldValueChanged(app, event)
            value = app.XposEditField.Value;
            app.XposSlider.Value = value;
            assignin('base', "XPosValue", value);
            
        end

        % Value changed function: YposEditField
        function YposEditFieldValueChanged(app, event)
            value = app.YposEditField.Value;
            app.YposSlider.Value = value;
            
        end

        % Value changed function: ZposEditField
        function ZposEditFieldValueChanged(app, event)
            value = app.ZposEditField.Value;
            app.ZposSlider.Value = value;
            
        end

        % Value changed function: RollEditField
        function RollEditFieldValueChanged(app, event)
            value = app.RollEditField.Value;
            app.RollSlider.Value = value;
            
        end

        % Value changed function: PitchEditField
        function PitchEditFieldValueChanged(app, event)
            value = app.PitchEditField.Value;
            app.PitchSlider.Value = value;
            
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            assignin('base', "selectedButton", selectedButton.Text);
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            assignin('base', "XPosValue", 0);
            app.XposSlider.Value = 0;
            app.XposEditField.Value = 0;
            assignin('base', "YPosValue", 0);
            app.YposSlider.Value = 0;
            app.YposEditField.Value = 0;
            assignin('base', "ZPosValue", 0);
            app.ZposSlider.Value = 0;
            app.ZposEditField.Value = 0;
            assignin('base', "yawValue", 0);
            app.YawSlider.Value = 0;
            app.YawEditField.Value = 0;
            assignin('base', "rollValue", 0);
            app.RollSlider.Value = 0;
            app.RollEditField.Value = 0;
            assignin('base', "pitchValue", 0);
            app.PitchSlider.Value = 0;
            app.PitchEditField.Value = 0;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ReadMeTab = uitab(app.TabGroup);
            app.ReadMeTab.Title = 'ReadMe';
            app.TextArea = uitextarea(app.ReadMeTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'The Bo{iiiiii}ng Company '; ''; '6 Axis Soft Robotic Fingertip Tester'};
            app.StaticTestLabel = uilabel(app.ReadMeTab);
            app.StaticTestLabel.Text = 'Static Test';
            app.TextArea_2 = uitextarea(app.ReadMeTab);
            app.TextArea_2.Value = {'Go to position functionality. '; 'User inputs:'; 'x,y,z in millimeters'; 'roll, pitch, yaw in degrees'; 't1, t2 in seconds '; 't1 = waiting time between poses '; 't2 = waiting time during a pose'};
            app.WaypointTestLabel = uilabel(app.ReadMeTab);
            app.WaypointTestLabel.Text = 'Waypoint Test';
            app.TextArea_3 = uitextarea(app.ReadMeTab);
            app.TextArea_3.Value = {'Go to waypoint functionality. '; 'User inputs:'; 'x,y,z in millimeters'; 'roll, pitch, yaw in degrees'; 't1, t2, t3 in seconds '; 't1 = waiting time between poses'; 't2 = waiting time during a pose'; 't3 = waiting time before next pose'};
            app.DynamicTestLabel = uilabel(app.ReadMeTab);
            app.DynamicTestLabel.Text = 'Dynamic Test';
            app.TextArea_4 = uitextarea(app.ReadMeTab);
            app.TextArea_4.Value = {'Dynamic functionality'; 'User inputs:'; 'Specify sinusodial amplitude and frequency for  up to six degrees of freedom'};
            app.StaticTab = uitab(app.TabGroup);
            app.StaticTab.Title = 'Static';
            app.XposSliderLabel = uilabel(app.StaticTab);
            app.XposSliderLabel.Text = 'Xpos';
            app.XposSlider = uislider(app.StaticTab);
            app.XposSlider.Limits = [0 20];
            app.XposSlider.ValueChangedFcn = createCallbackFcn(app, @xValueChanged, true);
            app.YposSliderLabel = uilabel(app.StaticTab);
            app.YposSliderLabel.Text = 'Ypos';
            app.YposSlider = uislider(app.StaticTab);
            app.YposSlider.Limits = [0 20];
            app.YposSlider.ValueChangedFcn = createCallbackFcn(app, @yValueChanged, true);
            app.ZposSliderLabel = uilabel(app.StaticTab);
            app.ZposSliderLabel.Text = 'Zpos';
            app.ZposSlider = uislider(app.StaticTab);
            app.ZposSlider.Limits = [0 20];
            app.ZposSlider.ValueChangedFcn = createCallbackFcn(app, @zValueChanged, true);
            app.RollSliderLabel = uilabel(app.StaticTab);
            app.RollSliderLabel.Text = {'Roll'; ''};
            app.RollSlider = uislider(app.StaticTab);
            app.RollSlider.Limits = [0 20];
            app.RollSlider.ValueChangedFcn = createCallbackFcn(app, @rollValueChanged, true);
            app.PitchSliderLabel = uilabel(app.StaticTab);
            app.PitchSliderLabel.Text = 'Pitch';
            app.PitchSlider = uislider(app.StaticTab);
            app.PitchSlider.Limits = [0 20];
            app.PitchSlider.ValueChangedFcn = createCallbackFcn(app, @pitchValueChanged, true);
            app.YawSliderLabel = uilabel(app.StaticTab);
            app.YawSliderLabel.Text = 'Yaw';
            app.YawSlider = uislider(app.StaticTab);
            app.YawSlider.Limits = [0 20];
            app.YawSlider.ValueChangedFcn = createCallbackFcn(app, @yawValueChanged, true);
            app.t1SliderLabel = uilabel(app.StaticTab);
            app.t1SliderLabel.Text = 't1';
            app.t1Slider = uislider(app.StaticTab);
            app.t1Slider.Limits = [0 20];
            app.t1Slider.ValueChangedFcn = createCallbackFcn(app, @t1SliderValueChanged, true);
            app.t2SliderLabel = uilabel(app.StaticTab);
            app.t2SliderLabel.Text = 't2';
            app.t2Slider = uislider(app.StaticTab);
            app.t2Slider.Limits = [0 20];
            app.t2Slider.ValueChangedFcn = createCallbackFcn(app, @t2SliderValueChanged, true);
            app.XposEditField = uieditfield(app.StaticTab, 'numeric');
            app.XposEditField.ValueDisplayFormat = '%6.5g';
            app.XposEditField.ValueChangedFcn = createCallbackFcn(app, @XposEditFieldValueChanged, true);
            app.YposEditField = uieditfield(app.StaticTab, 'numeric');
            app.YposEditField.ValueDisplayFormat = '%6.5g';
            app.YposEditField.ValueChangedFcn = createCallbackFcn(app, @YposEditFieldValueChanged, true);
            app.ZposEditField = uieditfield(app.StaticTab, 'numeric');
            app.ZposEditField.ValueDisplayFormat = '%6.5g';
            app.ZposEditField.ValueChangedFcn = createCallbackFcn(app, @ZposEditFieldValueChanged, true);
            app.RollEditField = uieditfield(app.StaticTab, 'numeric');
            app.RollEditField.ValueDisplayFormat = '%6.5g';
            app.RollEditField.ValueChangedFcn = createCallbackFcn(app, @RollEditFieldValueChanged, true);
            app.PitchEditField = uieditfield(app.StaticTab, 'numeric');
            app.PitchEditField.ValueDisplayFormat = '%6.5g';
            app.PitchEditField.ValueChangedFcn = createCallbackFcn(app, @PitchEditFieldValueChanged, true);
            app.YawEditField = uieditfield(app.StaticTab, 'numeric');
            app.YawEditField.ValueDisplayFormat = '%6.5g';
            app.t1EditField = uieditfield(app.StaticTab, 'numeric');
            app.t1EditField.ValueDisplayFormat = '%6.5g';
            app.t2EditField = uieditfield(app.StaticTab, 'numeric');
            app.t2EditField.ValueDisplayFormat = '%6.5g';
            app.ButtonGroup_2 = uibuttongroup(app.StaticTab);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.PrepareTestButton_2 = uitogglebutton(app.ButtonGroup_2);
            app.PrepareTestButton_2.Text = 'Prepare Test';
            app.PrepareTestButton_2.Value = true;
            app.CancelTestButton_2 = uitogglebutton(app.ButtonGroup_2);
            app.CancelTestButton_2.Text = 'Cancel Test';
            app.BeginTestButton_4 = uitogglebutton(app.ButtonGroup_2);
            app.BeginTestButton_4.Text = 'Begin Test';
            app.HomeButton = uibutton(app.StaticTab, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.WaypointTab = uitab(app.TabGroup);
            app.WaypointTab.Title = 'Waypoint';
            app.ButtonGroup_3 = uibuttongroup(app.WaypointTab);
            app.PrepareTestButton_3 = uitogglebutton(app.ButtonGroup_3);
            app.PrepareTestButton_3.Text = 'Prepare Test';
            app.PrepareTestButton_3.Value = true;
            app.BeginTestButton_3 = uitogglebutton(app.ButtonGroup_3);
            app.BeginTestButton_3.Text = {'Begin Test'; ''};
            app.CancelTestButton_3 = uitogglebutton(app.ButtonGroup_3);
            app.CancelTestButton_3.Text = 'Cancel Test';
            app.XposSlider_2Label = uilabel(app.WaypointTab);
            app.XposSlider_2Label.Text = 'Xpos';
            app.XposSlider_2 = uislider(app.WaypointTab);
            app.XposSlider_2.Limits = [0 20];
            app.YposSlider_2Label = uilabel(app.WaypointTab);
            app.YposSlider_2Label.Text = 'Ypos';
            app.YposSlider_2 = uislider(app.WaypointTab);
            app.YposSlider_2.Limits = [0 20];
            app.ZposSlider_2Label = uilabel(app.WaypointTab);
            app.ZposSlider_2Label.Text = 'Zpos';
            app.ZposSlider_2 = uislider(app.WaypointTab);
            app.ZposSlider_2.Limits = [0 20];
            app.RollSlider_2Label = uilabel(app.WaypointTab);
            app.RollSlider_2Label.Text = {'Roll'; ''};
            app.RollSlider_2 = uislider(app.WaypointTab);
            app.RollSlider_2.Limits = [0 20];
            app.PitchSlider_2Label = uilabel(app.WaypointTab);
            app.PitchSlider_2Label.Text = 'Pitch';
            app.PitchSlider_2 = uislider(app.WaypointTab);
            app.PitchSlider_2.Limits = [0 20];
            app.YawSlider_2Label = uilabel(app.WaypointTab);
            app.YawSlider_2Label.Text = 'Yaw';
            app.YawSlider_2 = uislider(app.WaypointTab);
            app.YawSlider_2.Limits = [0 20];
            app.t1Slider_2Label = uilabel(app.WaypointTab);
            app.t1Slider_2Label.Text = 't1';
            app.t1Slider_2 = uislider(app.WaypointTab);
            app.t1Slider_2.Limits = [0 20];
            app.t2Slider_2Label = uilabel(app.WaypointTab);
            app.t2Slider_2Label.Text = 't2';
            app.t2Slider_2 = uislider(app.WaypointTab);
            app.t2Slider_2.Limits = [0 20];
            app.XposEditField_2 = uieditfield(app.WaypointTab, 'numeric');
            app.XposEditField_2.ValueDisplayFormat = '%6.5g';
            app.YposEditField_2 = uieditfield(app.WaypointTab, 'numeric');
            app.YposEditField_2.ValueDisplayFormat = '%6.5g';
            app.ZposEditField_2 = uieditfield(app.WaypointTab, 'numeric');
            app.ZposEditField_2.ValueDisplayFormat = '%6.5g';
            app.RollEditField_4 = uieditfield(app.WaypointTab, 'numeric');
            app.RollEditField_4.ValueDisplayFormat = '%6.5g';
            app.PitchEditField_4 = uieditfield(app.WaypointTab, 'numeric');
            app.PitchEditField_4.ValueDisplayFormat = '%6.5g';
            app.YawEditField_4 = uieditfield(app.WaypointTab, 'numeric');
            app.YawEditField_4.ValueDisplayFormat = '%6.5g';
            app.t1EditField_2 = uieditfield(app.WaypointTab, 'numeric');
            app.t1EditField_2.ValueDisplayFormat = '%6.5g';
            app.t2EditField_2 = uieditfield(app.WaypointTab, 'numeric');
            app.t2EditField_2.ValueDisplayFormat = '%6.5g';
            app.DynamicTab = uitab(app.TabGroup);
            app.DynamicTab.Title = 'Dynamic';
            app.XEditFieldLabel = uilabel(app.DynamicTab);
            app.XEditFieldLabel.Text = 'X:';
            app.XEditField = uieditfield(app.DynamicTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.DynamicTab);
            app.YEditFieldLabel.Text = 'Y:';
            app.YEditField = uieditfield(app.DynamicTab, 'numeric');
            app.ZEditFieldLabel = uilabel(app.DynamicTab);
            app.ZEditFieldLabel.Text = 'Z:';
            app.ZEditField = uieditfield(app.DynamicTab, 'numeric');
            app.RollEditField_2Label = uilabel(app.DynamicTab);
            app.RollEditField_2Label.Text = 'Roll';
            app.RollEditField_2 = uieditfield(app.DynamicTab, 'numeric');
            app.PitchEditField_2Label = uilabel(app.DynamicTab);
            app.PitchEditField_2Label.Text = 'Pitch';
            app.PitchEditField_2 = uieditfield(app.DynamicTab, 'numeric');
            app.YawEditField_2Label = uilabel(app.DynamicTab);
            app.YawEditField_2Label.Text = 'Yaw';
            app.YawEditField_2 = uieditfield(app.DynamicTab, 'numeric');
            app.sinLabel = uilabel(app.DynamicTab);
            app.sinLabel.Text = '* sin (';
            app.sinLabel_2 = uilabel(app.DynamicTab);
            app.sinLabel_2.Text = '* sin (';
            app.sinLabel_3 = uilabel(app.DynamicTab);
            app.sinLabel_3.Text = '* sin (';
            app.sinLabel_4 = uilabel(app.DynamicTab);
            app.sinLabel_4.Text = '* sin (';
            app.sinLabel_5 = uilabel(app.DynamicTab);
            app.sinLabel_5.Text = '* sin (';
            app.sinLabel_6 = uilabel(app.DynamicTab);
            app.sinLabel_6.Text = '* sin (';
            app.XEditField_2 = uieditfield(app.DynamicTab, 'numeric');
            app.YEditField_2 = uieditfield(app.DynamicTab, 'numeric');
            app.ZEditField_2 = uieditfield(app.DynamicTab, 'numeric');
            app.RollEditField_3 = uieditfield(app.DynamicTab, 'numeric');
            app.PitchEditField_3 = uieditfield(app.DynamicTab, 'numeric');
            app.YawEditField_3 = uieditfield(app.DynamicTab, 'numeric');
            app.tLabel = uilabel(app.DynamicTab);
            app.tLabel.Text = '*t)';
            app.tLabel_2 = uilabel(app.DynamicTab);
            app.tLabel_2.Text = '*t)';
            app.tLabel_3 = uilabel(app.DynamicTab);
            app.tLabel_3.Text = '*t)';
            app.tLabel_4 = uilabel(app.DynamicTab);
            app.tLabel_4.Text = '*t)';
            app.tLabel_5 = uilabel(app.DynamicTab);
            app.tLabel_5.Text = '*t)';
            app.tLabel_6 = uilabel(app.DynamicTab);
            app.tLabel_6.Text = '*t)';
            app.ButtonGroup = uibuttongroup(app.DynamicTab);
            app.PrepareTestButton = uitogglebutton(app.ButtonGroup);
            app.PrepareTestButton.Text = 'Prepare Test';
            app.PrepareTestButton.Value = true;
            app.BeginTestButton = uitogglebutton(app.ButtonGroup);
            app.BeginTestButton.Text = {'Begin Test'; ''};
            app.CancelTestButton = uitogglebutton(app.ButtonGroup);
            app.CancelTestButton.Text = 'Cancel Test';
            app.CalibrateTab = uitab(app.TabGroup);
            app.CalibrateTab.Title = 'Calibrate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = merlet_ui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end