classdef merlet_ui_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        ReadMeTab                  matlab.ui.container.Tab
        TextArea                   matlab.ui.control.TextArea
        StaticTestLabel            matlab.ui.control.Label
        TextArea_2                 matlab.ui.control.TextArea
        WaypointTestLabel          matlab.ui.control.Label
        TextArea_3                 matlab.ui.control.TextArea
        DynamicTestLabel           matlab.ui.control.Label
        TextArea_4                 matlab.ui.control.TextArea
        StaticTab                  matlab.ui.container.Tab
        XposSliderLabel            matlab.ui.control.Label
        XposSlider                 matlab.ui.control.Slider
        YposSliderLabel            matlab.ui.control.Label
        YposSlider                 matlab.ui.control.Slider
        ZposSliderLabel            matlab.ui.control.Label
        ZposSlider                 matlab.ui.control.Slider
        RollSliderLabel            matlab.ui.control.Label
        RollSlider                 matlab.ui.control.Slider
        PitchSliderLabel           matlab.ui.control.Label
        PitchSlider                matlab.ui.control.Slider
        YawSliderLabel             matlab.ui.control.Label
        YawSlider                  matlab.ui.control.Slider
        XposEditField              matlab.ui.control.NumericEditField
        YposEditField              matlab.ui.control.NumericEditField
        ZposEditField              matlab.ui.control.NumericEditField
        RollEditField              matlab.ui.control.NumericEditField
        PitchEditField             matlab.ui.control.NumericEditField
        YawEditField               matlab.ui.control.NumericEditField
        Static_Mode_Button         matlab.ui.control.Button
        Static_Begin_Button        matlab.ui.control.Button
        Static_Cancel_Button       matlab.ui.control.Button
        ZeroButton                 matlab.ui.control.Button
        HomeButton                 matlab.ui.control.Button
        DynamicTab                 matlab.ui.container.Tab
        XEditFieldLabel            matlab.ui.control.Label
        Dynamic_X_A                matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        Dynamic_Y_A                matlab.ui.control.NumericEditField
        ZEditFieldLabel            matlab.ui.control.Label
        Dynamic_Z_A                matlab.ui.control.NumericEditField
        RollEditField_2Label       matlab.ui.control.Label
        Dynamic_Roll_A             matlab.ui.control.NumericEditField
        PitchEditField_2Label      matlab.ui.control.Label
        Dynamic_Pitch_A            matlab.ui.control.NumericEditField
        YawEditField_2Label        matlab.ui.control.Label
        Dynamic_Yaw_A              matlab.ui.control.NumericEditField
        sin2piLabel                matlab.ui.control.Label
        sinLabel_2                 matlab.ui.control.Label
        sinLabel_3                 matlab.ui.control.Label
        sinLabel_4                 matlab.ui.control.Label
        sinLabel_5                 matlab.ui.control.Label
        sinLabel_6                 matlab.ui.control.Label
        Dynamic_X_Hz               matlab.ui.control.NumericEditField
        Dynamic_Y_Hz               matlab.ui.control.NumericEditField
        Dynamic_Z_Hz               matlab.ui.control.NumericEditField
        Dynamic_Roll_Hz            matlab.ui.control.NumericEditField
        Dynamic_Pitch_Hz           matlab.ui.control.NumericEditField
        Dynamic_Yaw_Hz             matlab.ui.control.NumericEditField
        tLabel                     matlab.ui.control.Label
        tLabel_2                   matlab.ui.control.Label
        tLabel_3                   matlab.ui.control.Label
        tLabel_4                   matlab.ui.control.Label
        tLabel_5                   matlab.ui.control.Label
        tLabel_6                   matlab.ui.control.Label
        Dynamic_Cancel_Button      matlab.ui.control.Button
        Dynamic_Mode_Button        matlab.ui.control.Button
        Dynamic_Begin_Button       matlab.ui.control.Button
        TimeofTesttEditFieldLabel  matlab.ui.control.Label
        Dynamic_Duration           matlab.ui.control.NumericEditField
        dynamicdemoButton          matlab.ui.control.Button
        BeginXTestButton           matlab.ui.control.Button
        dynamicdemo2Button         matlab.ui.control.Button
        BeginYTestButton           matlab.ui.control.Button
        BeginZTestButton           matlab.ui.control.Button
        BeginRollTestButton        matlab.ui.control.Button
        BeginPitchTestButton       matlab.ui.control.Button
        BeginYawTestButton         matlab.ui.control.Button
        WaypointTab                matlab.ui.container.Tab
        SelectFileButton           matlab.ui.control.Button
        Waypoint_Cancel_Button     matlab.ui.control.Button
        Waypoint_Mode_Button       matlab.ui.control.Button
        Waypoint_Begin_Button      matlab.ui.control.Button
        TextArea_5                 matlab.ui.control.TextArea
        TestMotorTab               matlab.ui.container.Tab
        Label                      matlab.ui.control.Label
        motor0                     matlab.ui.control.Slider
        Label_2                    matlab.ui.control.Label
        motor1                     matlab.ui.control.Slider
        Label_3                    matlab.ui.control.Label
        motor2                     matlab.ui.control.Slider
        Label_4                    matlab.ui.control.Label
        motor3                     matlab.ui.control.Slider
        Label_5                    matlab.ui.control.Label
        motor4                     matlab.ui.control.Slider
        Label_6                    matlab.ui.control.Label
        motor5                     matlab.ui.control.Slider
        motor0_editField           matlab.ui.control.NumericEditField
        motor1_editField           matlab.ui.control.NumericEditField
        motor2_editField           matlab.ui.control.NumericEditField
        motor3_editField           matlab.ui.control.NumericEditField
        motor4_editField           matlab.ui.control.NumericEditField
        motor5_editField           matlab.ui.control.NumericEditField
        Motor_Mode_Button          matlab.ui.control.Button
        Motor_Begin_Button         matlab.ui.control.Button
        Motor_Cancel_Button        matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        Global_Begin_Test          matlab.ui.control.Button
        EndTestingButton           matlab.ui.control.Button
        CurrentStatusLabel         matlab.ui.control.Label
        Current_Status             matlab.ui.control.Label
        UITable                    matlab.ui.control.Table
    end

    
    properties (Access = public)
         hello = 7;
         inputs=[0;0;0;0;0;0] % Description
         stopTest = false;
    end
    
    methods (Access = private)
        
        function results = plot_merlet(app, new_p, b, l_new)
            %plot
            %platform
            %global new_p
            p_plot=[new_p new_p(:,1)];
            plot3(app.UIAxes, p_plot(1,:),p_plot(2,:),p_plot(3,:),'r-','LineWidth',2);
            axis(app.UIAxes,[-8,8,-8,8,-5,12]*1e4);
            axis(app.UIAxes,'equal')
            hold(app.UIAxes,'on')
            %hold on
            %joint
            joint_pos = b;
            joint_pos(3,:)=l_new;
            joint_plot = [joint_pos joint_pos(:,1)];
            %plot3(app.UIAxes, joint_plot(1,:),joint_plot(2,:),joint_plot(3,:));
            %hold(app.UIAxes,'on')
            %base
            b_plot=[b b(:,1)];
            plot3(app.UIAxes, b_plot(1,:),b_plot(2,:),b_plot(3,:),'r-','LineWidth',2);
            hold(app.UIAxes,'on')
            %hold on
            %link three hexagon
            for i=1:6
                link_plot = [new_p(:,i) joint_pos(:,i) b(:,i)];
                plot3(app.UIAxes, link_plot(1,:),link_plot(2,:),link_plot(3,:),'bo-','LineWidth',2,'MarkerIndices',2);
                hold(app.UIAxes,'on')
            end
            hold(app.UIAxes,'off')
        end
        
        function results = plot_dynamic_matrix(app,dynamic_matrix_new_p,...
            dynamic_matrix_b,dynamic_matrix_l_new,waypoints_num)          
            for t=1:waypoints_num
                plot3(app.UIAxes,1,1,1);
                new_p=dynamic_matrix_new_p(:,:,t);
                b=dynamic_matrix_b(:,:,t);
                l_new=dynamic_matrix_l_new(:,t);
                plot_merlet(app, new_p, b, l_new);
                %fprintf('here we are %6.2f.\n',t);
                pause(0.05);
            end
        end
        
        function results = convert_l_new(app,l_new)
            l_new=l_new;
            newData=l_new/10000;
            set(app.UITable,'Data',newData)
            assignin('base','leg_1_value',l_new(1));
            assignin('base','leg_2_value',l_new(2));
            assignin('base','leg_3_value',l_new(3));
            assignin('base','leg_4_value',l_new(4));
            assignin('base','leg_5_value',l_new(5));
            assignin('base','leg_6_value',l_new(6));          
        end
        function results = pure_convert_l_new(app,l_new)
            assignin('base','leg_1_value',l_new(1));
            assignin('base','leg_2_value',l_new(2));
            assignin('base','leg_3_value',l_new(3));
            assignin('base','leg_4_value',l_new(4));
            assignin('base','leg_5_value',l_new(5));
            assignin('base','leg_6_value',l_new(6));          
        end
        
        function results = generate_plot_trajectory(app,w_index,amplitude,freq)
            app.Current_Status.Text = 'Matlab is calculating trajectory';
            %generate matrix
            [dynamic_matrix,dynamic_matrix_vel,dynamic_matrix_new_p,dynamic_matrix_b,dynamic_matrix_l_new,waypoints_num]...
            =generate_dynamic_matrix(w_index,amplitude,freq);
            %send to working space for pyserial to grab
            assignin('base', 'dynamic_matrix', dynamic_matrix);
            assignin('base', 'dynamic_matrix_vel', dynamic_matrix_vel);
            app.Current_Status.Text = 'Simulating';
            %plot the trajectory on ui
            plot_dynamic_matrix(app,dynamic_matrix_new_p,...
            dynamic_matrix_b,dynamic_matrix_l_new,waypoints_num);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            assignin('base','connect',0);
            assignin('base','mode','False');
            assignin('base','begin_test_state',0);
            assignin('base','dynamic_demo_flag',0);
            assignin('base','dynamic_one_degree_flag',0);            
            app.Current_Status.Text = '<- Press Start Testing to begin';
            if (matlab.engine.isEngineShared() == 0)
                matlab.engine.shareEngine('sharedEngine')
            end
        end

        % Button pushed function: Global_Begin_Test
        function Global_Begin_TestPushed(app, event)
            assignin('base', 'connect', 1);
            app.Current_Status.Text = 'Prepare Desired Test Mode ->';
        end

        % Button pushed function: EndTestingButton
        function EndTestingButtonPushed(app, event)
            assignin('base', 'connect', 0);
            assignin('base', 'mode', 0);
            assignin('base', 'begin_test_state', 0);
            assignin('base', 'dynamic_demo_flag', 0);
            assignin('base', 'dynamic_one_degree_flag', 0);
            app.Current_Status.Text = 'Testing over, goodbye!';
        end

        % Button pushed function: Static_Mode_Button
        function Static_Mode_ButtonPushed(app, event)
        if (evalin('base','connect') == 1) 
            if(evalin('base','begin_test_state') == 1)
                app.Current_Status.Text = 'Please Cancel Current Test First';
            elseif(evalin('base','begin_test_state') == 0)
            assignin('base', 'mode', 'static')
            assignin('base', 'begin_test_state', 0)
            app.Current_Status.Text = 'Static Mode Selected ->';
            end
        elseif (evalin('base','connect') ~= 1)
            app.Current_Status.Text = 'Please Start Test First';
        end
        end

        % Button pushed function: Static_Begin_Button
        function Static_Begin_ButtonPushed(app, event)
            if (evalin('base','mode') == "static")    
                app.Current_Status.Text = 'Static Test Begun';
                assignin('base', 'begin_test_state', 1)
            elseif (evalin('base','mode') ~= "static")
                  app.Current_Status.Text = 'Please Prepare Static Test First'; 
            end
        end

        % Button pushed function: Static_Cancel_Button
        function Static_Cancel_ButtonPushed(app, event)
            if (evalin('base','mode') == "static") 
                app.Current_Status.Text = 'Static Test Cancelled';
                assignin('base', 'begin_test_state', 0)
            elseif (evalin('base','mode') ~= "static")
                  app.Current_Status.Text = 'Sorry, you are not in static test mode'; 
            end
        end

        % Button pushed function: ZeroButton
        function ZeroButtonPushed(app, event)
        if (evalin('base','mode') == "static" && evalin('base','begin_test_state') == 1)
            l_new=[0,0,0,0,0,0];
            convert_l_new(app,l_new);
            app.Current_Status.Text = 'Went to Zero position'; 
        else
            app.Current_Status.Text = 'Please Prepare then begin Static Test First';
        end
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
        if (evalin('base','mode') == "static" && evalin('base','begin_test_state') == 1)
            app.inputs = [0;0;0;0;0;0];
            plot3(app.UIAxes,1,1,1);
            [new_p, b, l_new] = merlet_ik(app.inputs);
            assignin('base','l_new',l_new);
            convert_l_new(app,l_new);
            plot_merlet(app, new_p, b, l_new)


            app.XposSlider.Value = 0;
            app.XposEditField.Value = 0;
            
            app.YposSlider.Value = 0;
            app.YposEditField.Value = 0;

            app.ZposSlider.Value = 0;
            app.ZposEditField.Value = 0;
            
            app.YawSlider.Value = 0;
            app.YawEditField.Value = 0;
            
            app.RollSlider.Value = 0;
            app.RollEditField.Value = 0;
            
            app.PitchSlider.Value = 0;
            app.PitchEditField.Value = 0;
            app.Current_Status.Text = 'Went to Home position'; 
           else
            app.Current_Status.Text = 'Please Prepare then begin Static Test First';
        end 
        end

        % Value changed function: RollSlider
        function rollValueChanged(app, event)
            value = app.RollSlider.Value;
            app.RollEditField.Value = value;

            app.inputs(1)=value;
            plot3(app.UIAxes,1,1,1);
            [new_p, b, l_new] = merlet_ik(app.inputs);
            assignin('base','l_new',l_new);
            convert_l_new(app,l_new);
            plot_merlet(app, new_p, b, l_new);
        end

        % Value changed function: PitchSlider
        function pitchValueChanged(app, event)
            value = app.PitchSlider.Value;
            app.PitchEditField.Value = value;
            app.inputs(2)=value;
            plot3(app.UIAxes,1,1,1);
            [new_p, b, l_new] = merlet_ik(app.inputs);
            assignin('base','l_new',l_new);
            convert_l_new(app,l_new);
            plot_merlet(app, new_p, b, l_new)
        end

        % Value changed function: YawSlider
        function yawValueChanged(app, event)
            value = app.YawSlider.Value;
            app.YawEditField.Value = value;   
            app.inputs(3)=value;
            plot3(app.UIAxes,1,1,1);
            [new_p, b, l_new] = merlet_ik(app.inputs);
            assignin('base','l_new',l_new);
            convert_l_new(app,l_new);
            plot_merlet(app, new_p, b, l_new)
        end

        % Value changed function: XposSlider
        function xValueChanged(app, event)
            value = app.XposSlider.Value;
            app.XposEditField.Value = value;
            app.inputs(4)=value;
            plot3(app.UIAxes,1,1,1);
            [new_p, b, l_new] = merlet_ik(app.inputs);
            assignin('base','l_new',l_new);
            convert_l_new(app,l_new);
            plot_merlet(app, new_p, b, l_new)
        end

        % Value changed function: YposSlider
        function yValueChanged(app, event)
            value = app.YposSlider.Value;
            app.YposEditField.Value = value;
            app.inputs(5)=value;
            plot3(app.UIAxes,1,1,1);
            [new_p, b, l_new] = merlet_ik(app.inputs);
            assignin('base','l_new',l_new);
            convert_l_new(app,l_new);
            plot_merlet(app, new_p, b, l_new)
        end

        % Value changed function: ZposSlider
        function zValueChanged(app, event)
            value = app.ZposSlider.Value;
            app.ZposEditField.Value = value;
            app.inputs(6)=value;
            plot3(app.UIAxes,1,1,1);
            [new_p, b, l_new] = merlet_ik(app.inputs);
            assignin('base','l_new',l_new);
            convert_l_new(app,l_new);
            plot_merlet(app, new_p, b, l_new)
        end

        % Value changed function: XposEditField
        function XposEditFieldValueChanged(app, event)
            value = app.XposEditField.Value;
            app.XposSlider.Value = value;
            app.inputs(4)=value;
            plot3(app.UIAxes,1,1,1);
            [new_p, b, l_new] = merlet_ik(app.inputs);
            assignin('base','l_new',l_new);
            convert_l_new(app,l_new);
            plot_merlet(app, new_p, b, l_new)
            
        end

        % Value changed function: YposEditField
        function YposEditFieldValueChanged(app, event)
            value = app.YposEditField.Value;
            app.YposSlider.Value = value;
            app.inputs(5)=value;
            plot3(app.UIAxes,1,1,1);
            [new_p, b, l_new] = merlet_ik(app.inputs);
            assignin('base','l_new',l_new);
            convert_l_new(app,l_new);
            plot_merlet(app, new_p, b, l_new)
            
        end

        % Value changed function: ZposEditField
        function ZposEditFieldValueChanged(app, event)
            value = app.ZposEditField.Value;
            app.ZposSlider.Value = value;
            
            app.inputs(6)=value;
            plot3(app.UIAxes,1,1,1);
            [new_p, b, l_new] = merlet_ik(app.inputs);
                        assignin('base','l_new',l_new);
            convert_l_new(app,l_new);
            plot_merlet(app, new_p, b, l_new)
        end

        % Value changed function: RollEditField
        function RollEditFieldValueChanged(app, event)
            value = app.RollEditField.Value;
            app.RollSlider.Value = value;
            app.inputs(1)=value;
            plot3(app.UIAxes,1,1,1);
            [new_p, b, l_new] = merlet_ik(app.inputs);
            assignin('base','l_new',l_new);
            convert_l_new(app,l_new);
            plot_merlet(app, new_p, b, l_new);
            
        end

        % Value changed function: PitchEditField
        function PitchEditFieldValueChanged(app, event)
            value = app.PitchEditField.Value;
            app.PitchSlider.Value = value;
            app.inputs(2)=value;
            plot3(app.UIAxes,1,1,1);
            [new_p, b, l_new] = merlet_ik(app.inputs);
            assignin('base','l_new',l_new);
            convert_l_new(app,l_new);
            plot_merlet(app, new_p, b, l_new)
            
        end

        % Value changed function: YawEditField
        function YawEditFieldValueChanged(app, event)
            value = app.YawEditField.Value;
            app.YawSlider.Value = value;
            app.inputs(3)=value;
            plot3(app.UIAxes,1,1,1);
            [new_p, b, l_new] = merlet_ik(app.inputs);
            assignin('base','l_new',l_new);
            convert_l_new(app,l_new);
            plot_merlet(app, new_p, b, l_new)
        end

        % Callback function
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            assignin('base', "selectedButton", selectedButton.Text);
            assignin('base', 'mode', 'static');

        end

        % Callback function
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            assignin('base','mode','motorTest');
            assignin('base', "selectedButton", selectedButton.Text);
            assignin('base','l_new',[0;0;0;0;0;0]);
            
        end

        % Button pushed function: Dynamic_Mode_Button
        function Dynamic_Mode_ButtonPushed(app, event)
        if (evalin('base','connect') == 1)
            if(evalin('base','begin_test_state') == 1)
                app.Current_Status.Text = 'Please Cancel Current Test First';
            elseif(evalin('base','begin_test_state') == 0)
                assignin('base', 'mode', 'dynamic');
                assignin('base', 'begin_test_state', 0);
                assignin('base', 'dynamic_demo_flag', 0);
                assignin('base', 'dynamic_one_degree_flag', 0);
                app.Current_Status.Text = 'Dynamic Mode Selected ->';   
            end           
        elseif (evalin('base','connect') ~= 1)
            app.Current_Status.Text = 'Please Start Test First';  
        end
        end

        % Button pushed function: Dynamic_Begin_Button
        function Dynamic_Begin_ButtonPushed(app, event)
            if (evalin('base','mode') == "dynamic")    
                app.Current_Status.Text = ['Dynamic Test Begin, you can choose demo' ...
                 ' or self-defined test'];
                assignin('base', 'begin_test_state', 1)
            elseif (evalin('base','mode') ~= "dynamic")
                  app.Current_Status.Text = 'Please Prepare Dynamic Test First'; 
            end        
        end

        % Button pushed function: Dynamic_Cancel_Button
        function Dynamic_Cancel_ButtonPushed(app, event)
            if (evalin('base','mode') == "dynamic")
                app.Current_Status.Text = 'Dynamic Test Cancelled';
                assignin('base', 'begin_test_state', 0)
                assignin('base', 'dynamic_demo_flag', 0)
                assignin('base', 'dynamic_one_degree_flag', 0);
            elseif (evalin('base','mode') ~= "dynamic")
                  app.Current_Status.Text = 'Please Prepare Dynamic Test First'; 
            end        
        end

        % Button pushed function: dynamicdemoButton
        function Dynamic_Demo_ButtonPushed(app, event)
            if (evalin('base','mode') == "dynamic" && evalin('base','begin_test_state') == 1)
                assignin('base', 'dynamic_demo_flag', 1);
                app.Current_Status.Text = 'Begin Demo Dynamic Test';
            elseif (evalin('base','mode') ~= "dynamic")
                  app.Current_Status.Text = 'Please Prepare Dynamic Test First'; 
            elseif (evalin('base','mode') == "dynamic" && evalin('base','begin_test_state') == 0)
                app.Current_Status.Text = 'Please Begin Dynamic Test First'; 
            end      
        end

        % Button pushed function: dynamicdemo2Button
        function Dynamic_Demo2_ButtonPushed(app, event)
            if (evalin('base','mode') == "dynamic" && evalin('base','begin_test_state') == 1)
                assignin('base', 'dynamic_demo_flag', 2);
                app.Current_Status.Text = 'Begin Demo2 Dynamic Test'; 
            elseif (evalin('base','mode') ~= "dynamic")
                  app.Current_Status.Text = 'Please Prepare Dynamic Test First'; 
            elseif (evalin('base','mode') == "dynamic" && evalin('base','begin_test_state') == 0)
                app.Current_Status.Text = 'Please Begin Dynamic Test First';  
            end  
        end

        % Button pushed function: BeginXTestButton
        function BeginXTestButtonPushed(app, event)
            if (evalin('base','mode') == "dynamic" && evalin('base','begin_test_state') == 1) 
                if(app.Dynamic_X_A.Value==0 || app.Dynamic_X_Hz.Value==0)
                    app.Current_Status.Text = 'Please Enter Amptitude and Frequency';
                else
                    %value 1 reprensents changing x, plot then assign
                    %matrix to working space
                    generate_plot_trajectory(app,4,app.Dynamic_X_A.Value,app.Dynamic_X_Hz.Value)
                    %send flag to pyserial to start
                    app.Current_Status.Text = 'Start Moving';
                    assignin('base', 'dynamic_one_degree_flag', 1);
                end
            elseif (evalin('base','mode') ~= "dynamic")
                  app.Current_Status.Text = 'Please Prepare Dynamic Test First'; 
            elseif (evalin('base','mode') == "dynamic" && evalin('base','begin_test_state') == 0)
                app.Current_Status.Text = 'Please Begin Dynamic Test First'; 
            end               
        end

        % Button pushed function: BeginYTestButton
        function BeginYTestButtonPushed(app, event)
            if (evalin('base','mode') == "dynamic" && evalin('base','begin_test_state') == 1) 
                if(app.Dynamic_Y_A.Value==0 || app.Dynamic_Y_Hz.Value==0)
                    app.Current_Status.Text = 'Please Enter Amptitude and Frequency';
                else
                    %value 1 reprensents changing x, plot then assign
                    %matrix to working space
                    generate_plot_trajectory(app,5,app.Dynamic_Y_A.Value,app.Dynamic_Y_Hz.Value)
                    %send flag to pyserial to start
                    app.Current_Status.Text = 'Start Moving';
                    assignin('base', 'dynamic_one_degree_flag', 1);
                end
            elseif (evalin('base','mode') ~= "dynamic")
                  app.Current_Status.Text = 'Please Prepare Dynamic Test First'; 
            elseif (evalin('base','mode') == "dynamic" && evalin('base','begin_test_state') == 0)
                app.Current_Status.Text = 'Please Begin Dynamic Test First'; 
            end             
        end

        % Button pushed function: BeginZTestButton
        function BeginZTestButtonPushed(app, event)
            if (evalin('base','mode') == "dynamic" && evalin('base','begin_test_state') == 1) 
                if(app.Dynamic_Z_A.Value==0 || app.Dynamic_Z_Hz.Value==0)
                    app.Current_Status.Text = 'Please Enter Amptitude and Frequency';
                else
                    %value 1 reprensents changing x, plot then assign
                    %matrix to working space
                    generate_plot_trajectory(app,6,app.Dynamic_Z_A.Value,app.Dynamic_Z_Hz.Value)
                    %send flag to pyserial to start
                    app.Current_Status.Text = 'Start Moving';
                    assignin('base', 'dynamic_one_degree_flag', 1);
                end
            elseif (evalin('base','mode') ~= "dynamic")
                  app.Current_Status.Text = 'Please Prepare Dynamic Test First'; 
            elseif (evalin('base','mode') == "dynamic" && evalin('base','begin_test_state') == 0)
                app.Current_Status.Text = 'Please Begin Dynamic Test First'; 
            end             
        end

        % Button pushed function: BeginRollTestButton
        function BeginRollTestButtonPushed(app, event)
            if (evalin('base','mode') == "dynamic" && evalin('base','begin_test_state') == 1) 
                if(app.Dynamic_Roll_A.Value==0 || app.Dynamic_Roll_Hz.Value==0)
                    app.Current_Status.Text = 'Please Enter Amptitude and Frequency';
                else
                    %value 1 reprensents changing x, plot then assign
                    %matrix to working space
                    generate_plot_trajectory(app,1,app.Dynamic_Roll_A.Value,app.Dynamic_Roll_Hz.Value)
                    %send flag to pyserial to start
                    app.Current_Status.Text = 'Start Moving';
                    assignin('base', 'dynamic_one_degree_flag', 1);
                end
            elseif (evalin('base','mode') ~= "dynamic")
                  app.Current_Status.Text = 'Please Prepare Dynamic Test First'; 
            elseif (evalin('base','mode') == "dynamic" && evalin('base','begin_test_state') == 0)
                app.Current_Status.Text = 'Please Begin Dynamic Test First'; 
            end             
        end

        % Button pushed function: BeginPitchTestButton
        function BeginPitchTestButtonPushed(app, event)
            if (evalin('base','mode') == "dynamic" && evalin('base','begin_test_state') == 1) 
                if(app.Dynamic_Pitch_A.Value==0 || app.Dynamic_Pitch_Hz.Value==0)
                    app.Current_Status.Text = 'Please Enter Amptitude and Frequency';
                else
                    %value 1 reprensents changing x, plot then assign
                    %matrix to working space
                    generate_plot_trajectory(app,2,app.Dynamic_Pitch_A.Value,app.Dynamic_Pitch_Hz.Value)
                    %send flag to pyserial to start
                    app.Current_Status.Text = 'Start Moving';
                    assignin('base', 'dynamic_one_degree_flag', 1);
                end
            elseif (evalin('base','mode') ~= "dynamic")
                  app.Current_Status.Text = 'Please Prepare Dynamic Test First'; 
            elseif (evalin('base','mode') == "dynamic" && evalin('base','begin_test_state') == 0)
                app.Current_Status.Text = 'Please Begin Dynamic Test First'; 
            end             
        end

        % Button pushed function: BeginYawTestButton
        function BeginYawTestButtonPushed(app, event)
            if (evalin('base','mode') == "dynamic" && evalin('base','begin_test_state') == 1) 
                if(app.Dynamic_Yaw_A.Value==0 || app.Dynamic_Yaw_Hz.Value==0)
                    app.Current_Status.Text = 'Please Enter Amptitude and Frequency';
                else
                    %value 1 reprensents changing x, plot then assign
                    %matrix to working space
                    generate_plot_trajectory(app,3,app.Dynamic_Yaw_A.Value,app.Dynamic_Yaw_Hz.Value)
                    %send flag to pyserial to start
                    app.Current_Status.Text = 'Start Moving';
                    assignin('base', 'dynamic_one_degree_flag', 1);
                end
            elseif (evalin('base','mode') ~= "dynamic")
                  app.Current_Status.Text = 'Please Prepare Dynamic Test First'; 
            elseif (evalin('base','mode') == "dynamic" && evalin('base','begin_test_state') == 0)
                app.Current_Status.Text = 'Please Begin Dynamic Test First'; 
            end             
        end

        % Button pushed function: Waypoint_Mode_Button
        function Waypoint_Mode_ButtonPushed(app, event)
        if (evalin('base','connect') == 1)
            if(evalin('base','begin_test_state') == 1)
                app.Current_Status.Text = 'Please Cancel Current Test First';
            elseif(evalin('base','begin_test_state') == 0)
                assignin('base', 'mode', 'waypoint');
                assignin('base', 'begin_test_state', 0);
                assignin('base','waypoints_matrix_flag',0);
                app.Current_Status.Text = 'Waypoint Mode Selected ->';   
            end           
        elseif (evalin('base','connect') ~= 1)
            app.Current_Status.Text = 'Please Start Test First';  
        end  
        end

        % Button pushed function: Waypoint_Begin_Button
        function Waypoint_Begin_ButtonPushed(app, event)
            if (evalin('base','mode') == "waypoint")    
                app.Current_Status.Text = 'Waypoint Test Begin, Please select a csv file to perform';
                assignin('base', 'begin_test_state', 1)
                assignin('base','waypoints_matrix_flag',0);
            elseif (evalin('base','mode') ~= "waypoint")
                  app.Current_Status.Text = 'Please Prepare Waypoint Test First'; 
            end  
        end

        % Button pushed function: Waypoint_Cancel_Button
        function Waypoint_Cancel_ButtonPushed(app, event)
            if (evalin('base','mode') == "waypoint")
                app.Current_Status.Text = 'Waypoint Test Cancelled';
                assignin('base', 'begin_test_state', 0)
                assignin('base','waypoints_matrix_flag',0);
            elseif (evalin('base','mode') ~= "waypoint")
                  app.Current_Status.Text = 'Please Prepare Waypoint Test First'; 
            end             
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            if (evalin('base','mode') == "waypoint" && evalin('base','begin_test_state') == 1)
                [filename, filepath]=uigetfile({'*.csv'}, 'Select File');
                cd(filepath);
                rawdata1=load(filename);
                [m,n]=size(rawdata1)
                %for simulation
                for i =1:m
                    input = transpose(rawdata1(i,1:6));
                    [new_p, b, l_new] = merlet_ik(input);
                    %assignin('base','l_new',l_new);
                    %convert_l_new(app,l_new);
                    plot_merlet(app, new_p, b, l_new)
                    pause(0.4);
                end
                %send to pyserial
                waypoints=rawdata1;
                waypoints_matrix=generate_waypoint_matrix(waypoints);
                assignin('base','test',rawdata1);
                assignin('base','waypoints_matrix',waypoints_matrix);
                assignin('base','waypoints_matrix_flag',1);
            elseif (evalin('base','mode') ~= "waypoint")
                  app.Current_Status.Text = 'Please Prepare Dynamic Test First'; 
            elseif (evalin('base','mode') == "waypoint" && evalin('base','begin_test_state') == 0)
                app.Current_Status.Text = 'Please Begin Waypoint Test First'; 
            end 

        end

        % Button pushed function: Motor_Mode_Button
        function Motor_Mode_ButtonPushed(app, event)
        if (evalin('base','connect') == 1)
            if(evalin('base','begin_test_state') == 1)
                app.Current_Status.Text = 'Please Cancel Current Test First';
            elseif(evalin('base','begin_test_state') == 0)
                assignin('base', 'mode', 'motor');
                assignin('base', 'begin_test_state', 0);
                app.Current_Status.Text = 'Motor Mode Selected ->';   
            end           
        elseif (evalin('base','connect') ~= 1)
            app.Current_Status.Text = 'Please Start Test First';  
        end              
        end

        % Button pushed function: Motor_Begin_Button
        function Motor_Begin_ButtonPushed(app, event)
            if (evalin('base','mode') == "motor")    
                app.Current_Status.Text = 'Motor Test Begin';
                assignin('base', 'begin_test_state', 1)
            elseif (evalin('base','mode') ~= "motor")
                  app.Current_Status.Text = 'Please Prepare Motor Test First'; 
            end              
        end

        % Button pushed function: Motor_Cancel_Button
        function Motor_Cancel_ButtonPushed(app, event)
            if (evalin('base','mode') == "motor")
                app.Current_Status.Text = 'Motor Test Cancelled';
                assignin('base', 'begin_test_state', 0)
            elseif (evalin('base','mode') ~= "motor")
                  app.Current_Status.Text = 'Please Prepare Motor Test First'; 
            end              
        end

        % Value changed function: motor0
        function motor0ValueChanged(app, event)
            value = app.motor0.Value;
            %value=value*10000;
            app.motor0_editField.Value = value;
            %l_new = evalin('base','l_new');
            l_new=[app.motor0.Value,app.motor1.Value,app.motor2.Value...
                app.motor3.Value,app.motor4.Value,app.motor5.Value];
            l_new(1) = value;
            assignin('base', 'l_new', l_new);
            convert_l_new(app, l_new);
            %[new_p, b, l_new,l_relative_move]=merlet_fk(l_new,[0;0;0;0;0;0]);
            %plot_merlet(app,new_p,b,l_new);
        end

        % Value changed function: motor1
        function motor1ValueChanged(app, event)
            value = app.motor1.Value;
            app.motor1_editField.Value = value;
            %l_new = evalin('base','l_new');
            l_new=[app.motor0.Value,app.motor1.Value,app.motor2.Value...
                app.motor3.Value,app.motor4.Value,app.motor5.Value];
            l_new(2) = value;
            assignin('base', 'l_new', l_new);
            convert_l_new(app, l_new);
        end

        % Value changed function: motor3
        function motor3ValueChanged(app, event)
            value = app.motor3.Value;
            app.motor3_editField.Value = value;
            %l_new = evalin('base','l_new');
            l_new=[app.motor0.Value,app.motor1.Value,app.motor2.Value...
                app.motor3.Value,app.motor4.Value,app.motor5.Value];
            l_new(4) = value;
            assignin('base', 'l_new', l_new);
            convert_l_new(app, l_new);
        end

        % Value changed function: motor2
        function motor2ValueChanged(app, event)
            value = app.motor2.Value;
            app.motor2_editField.Value = value;
            %l_new = evalin('base','l_new');
            l_new=[app.motor0.Value,app.motor1.Value,app.motor2.Value...
                app.motor3.Value,app.motor4.Value,app.motor5.Value];
            l_new(3) = value;
            assignin('base', 'l_new', l_new);
            convert_l_new(app, l_new);
        end

        % Value changed function: motor4
        function motor4ValueChanged(app, event)
            value = app.motor4.Value;
            app.motor4_editField.Value = value;
            %l_new = evalin('base','l_new');
            l_new=[app.motor0.Value,app.motor1.Value,app.motor2.Value...
                app.motor3.Value,app.motor4.Value,app.motor5.Value];
            l_new(5) = value;
            assignin('base', 'l_new', l_new);
            convert_l_new(app, l_new);
        end

        % Value changed function: motor5
        function motor5ValueChanged(app, event)
            value = app.motor5.Value;
            app.motor5_editField.Value = value;
            %l_new = evalin('base','l_new');
            l_new=[app.motor0.Value,app.motor1.Value,app.motor2.Value...
                app.motor3.Value,app.motor4.Value,app.motor5.Value];
            l_new(6) = value;
            assignin('base', 'l_new', l_new);
            convert_l_new(app, l_new);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ReadMeTab = uitab(app.TabGroup);
            app.ReadMeTab.Title = 'ReadMe';
            app.TextArea = uitextarea(app.ReadMeTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'The Bo{iiiiii}ng Company '; ''; '6 Axis Soft Robotic Fingertip Tester'};
            app.StaticTestLabel = uilabel(app.ReadMeTab);
            app.StaticTestLabel.Text = 'Static Test';
            app.TextArea_2 = uitextarea(app.ReadMeTab);
            app.TextArea_2.Value = {'Go to position functionality. '; 'User inputs:'; 'x,y,z in millimeters'; 'roll, pitch, yaw in degrees'; 't1, t2 in seconds '; 't1 = waiting time between poses '; 't2 = waiting time during a pose'};
            app.WaypointTestLabel = uilabel(app.ReadMeTab);
            app.WaypointTestLabel.Text = 'Waypoint Test';
            app.TextArea_3 = uitextarea(app.ReadMeTab);
            app.TextArea_3.Value = {'Go to waypoint functionality. '; 'User inputs:'; 'x,y,z in millimeters'; 'roll, pitch, yaw in degrees'; 't1, t2, t3 in seconds '; 't1 = waiting time between poses'; 't2 = waiting time during a pose'; 't3 = waiting time before next pose'};
            app.DynamicTestLabel = uilabel(app.ReadMeTab);
            app.DynamicTestLabel.Text = 'Dynamic Test';
            app.TextArea_4 = uitextarea(app.ReadMeTab);
            app.TextArea_4.Value = {'Dynamic functionality'; 'User inputs:'; 'Specify sinusodial amplitude and frequency for  up to six degrees of freedom'};
            app.StaticTab = uitab(app.TabGroup);
            app.StaticTab.Title = 'Static';
            app.XposSliderLabel = uilabel(app.StaticTab);
            app.XposSliderLabel.Text = 'Xpos';
            app.XposSlider = uislider(app.StaticTab);
            app.XposSlider.Limits = [-2 2];
            app.XposSlider.ValueChangedFcn = createCallbackFcn(app, @xValueChanged, true);
            app.YposSliderLabel = uilabel(app.StaticTab);
            app.YposSliderLabel.Text = 'Ypos';
            app.YposSlider = uislider(app.StaticTab);
            app.YposSlider.Limits = [-1 1];
            app.YposSlider.ValueChangedFcn = createCallbackFcn(app, @yValueChanged, true);
            app.ZposSliderLabel = uilabel(app.StaticTab);
            app.ZposSliderLabel.Text = 'Zpos';
            app.ZposSlider = uislider(app.StaticTab);
            app.ZposSlider.Limits = [-1 1];
            app.ZposSlider.ValueChangedFcn = createCallbackFcn(app, @zValueChanged, true);
            app.RollSliderLabel = uilabel(app.StaticTab);
            app.RollSliderLabel.Text = {'Roll'; ''};
            app.RollSlider = uislider(app.StaticTab);
            app.RollSlider.Limits = [-0.3 0.3];
            app.RollSlider.ValueChangedFcn = createCallbackFcn(app, @rollValueChanged, true);
            app.PitchSliderLabel = uilabel(app.StaticTab);
            app.PitchSliderLabel.Text = 'Pitch';
            app.PitchSlider = uislider(app.StaticTab);
            app.PitchSlider.Limits = [-0.3 0.3];
            app.PitchSlider.ValueChangedFcn = createCallbackFcn(app, @pitchValueChanged, true);
            app.YawSliderLabel = uilabel(app.StaticTab);
            app.YawSliderLabel.Text = 'Yaw';
            app.YawSlider = uislider(app.StaticTab);
            app.YawSlider.Limits = [-0.3 0.3];
            app.YawSlider.ValueChangedFcn = createCallbackFcn(app, @yawValueChanged, true);
            app.XposEditField = uieditfield(app.StaticTab, 'numeric');
            app.XposEditField.ValueDisplayFormat = '%6.5g';
            app.XposEditField.ValueChangedFcn = createCallbackFcn(app, @XposEditFieldValueChanged, true);
            app.YposEditField = uieditfield(app.StaticTab, 'numeric');
            app.YposEditField.ValueDisplayFormat = '%6.5g';
            app.YposEditField.ValueChangedFcn = createCallbackFcn(app, @YposEditFieldValueChanged, true);
            app.ZposEditField = uieditfield(app.StaticTab, 'numeric');
            app.ZposEditField.ValueDisplayFormat = '%6.5g';
            app.ZposEditField.ValueChangedFcn = createCallbackFcn(app, @ZposEditFieldValueChanged, true);
            app.RollEditField = uieditfield(app.StaticTab, 'numeric');
            app.RollEditField.ValueDisplayFormat = '%6.5g';
            app.RollEditField.ValueChangedFcn = createCallbackFcn(app, @RollEditFieldValueChanged, true);
            app.PitchEditField = uieditfield(app.StaticTab, 'numeric');
            app.PitchEditField.ValueDisplayFormat = '%6.5g';
            app.PitchEditField.ValueChangedFcn = createCallbackFcn(app, @PitchEditFieldValueChanged, true);
            app.YawEditField = uieditfield(app.StaticTab, 'numeric');
            app.YawEditField.ValueDisplayFormat = '%6.5g';
            app.YawEditField.ValueChangedFcn = createCallbackFcn(app, @YawEditFieldValueChanged, true);
            app.Static_Mode_Button = uibutton(app.StaticTab, 'push');
            app.Static_Mode_Button.ButtonPushedFcn = createCallbackFcn(app, @Static_Mode_ButtonPushed, true);
            app.Static_Mode_Button.Text = 'Prepare Static Test';
            app.Static_Begin_Button = uibutton(app.StaticTab, 'push');
            app.Static_Begin_Button.ButtonPushedFcn = createCallbackFcn(app, @Static_Begin_ButtonPushed, true);
            app.Static_Begin_Button.Text = 'Begin Test';
            app.Static_Cancel_Button = uibutton(app.StaticTab, 'push');
            app.Static_Cancel_Button.ButtonPushedFcn = createCallbackFcn(app, @Static_Cancel_ButtonPushed, true);
            app.Static_Cancel_Button.Text = 'Cancel Test';
            app.ZeroButton = uibutton(app.StaticTab, 'push');
            app.ZeroButton.ButtonPushedFcn = createCallbackFcn(app, @ZeroButtonPushed, true);
            app.ZeroButton.Text = 'Zero';
            app.HomeButton = uibutton(app.StaticTab, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.DynamicTab = uitab(app.TabGroup);
            app.DynamicTab.Title = 'Dynamic';
            app.XEditFieldLabel = uilabel(app.DynamicTab);
            app.XEditFieldLabel.Text = 'X:';
            app.Dynamic_X_A = uieditfield(app.DynamicTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.DynamicTab);
            app.YEditFieldLabel.Text = 'Y:';
            app.Dynamic_Y_A = uieditfield(app.DynamicTab, 'numeric');
            app.ZEditFieldLabel = uilabel(app.DynamicTab);
            app.ZEditFieldLabel.Text = 'Z:';
            app.Dynamic_Z_A = uieditfield(app.DynamicTab, 'numeric');
            app.RollEditField_2Label = uilabel(app.DynamicTab);
            app.RollEditField_2Label.Text = 'Roll';
            app.Dynamic_Roll_A = uieditfield(app.DynamicTab, 'numeric');
            app.PitchEditField_2Label = uilabel(app.DynamicTab);
            app.PitchEditField_2Label.Text = 'Pitch';
            app.Dynamic_Pitch_A = uieditfield(app.DynamicTab, 'numeric');
            app.YawEditField_2Label = uilabel(app.DynamicTab);
            app.YawEditField_2Label.Text = 'Yaw';
            app.Dynamic_Yaw_A = uieditfield(app.DynamicTab, 'numeric');
            app.sin2piLabel = uilabel(app.DynamicTab);
            app.sin2piLabel.Text = '* sin2pi (';
            app.sinLabel_2 = uilabel(app.DynamicTab);
            app.sinLabel_2.Text = '* sin (';
            app.sinLabel_3 = uilabel(app.DynamicTab);
            app.sinLabel_3.Text = '* sin (';
            app.sinLabel_4 = uilabel(app.DynamicTab);
            app.sinLabel_4.Text = '* sin (';
            app.sinLabel_5 = uilabel(app.DynamicTab);
            app.sinLabel_5.Text = '* sin (';
            app.sinLabel_6 = uilabel(app.DynamicTab);
            app.sinLabel_6.Text = '* sin (';
            app.Dynamic_X_Hz = uieditfield(app.DynamicTab, 'numeric');
            app.Dynamic_Y_Hz = uieditfield(app.DynamicTab, 'numeric');
            app.Dynamic_Z_Hz = uieditfield(app.DynamicTab, 'numeric');
            app.Dynamic_Roll_Hz = uieditfield(app.DynamicTab, 'numeric');
            app.Dynamic_Pitch_Hz = uieditfield(app.DynamicTab, 'numeric');
            app.Dynamic_Yaw_Hz = uieditfield(app.DynamicTab, 'numeric');
            app.tLabel = uilabel(app.DynamicTab);
            app.tLabel.Text = '*t)';
            app.tLabel_2 = uilabel(app.DynamicTab);
            app.tLabel_2.Text = '*t)';
            app.tLabel_3 = uilabel(app.DynamicTab);
            app.tLabel_3.Text = '*t)';
            app.tLabel_4 = uilabel(app.DynamicTab);
            app.tLabel_4.Text = '*t)';
            app.tLabel_5 = uilabel(app.DynamicTab);
            app.tLabel_5.Text = '*t)';
            app.tLabel_6 = uilabel(app.DynamicTab);
            app.tLabel_6.Text = '*t)';
            app.Dynamic_Cancel_Button = uibutton(app.DynamicTab, 'push');
            app.Dynamic_Cancel_Button.ButtonPushedFcn = createCallbackFcn(app, @Dynamic_Cancel_ButtonPushed, true);
            app.Dynamic_Cancel_Button.Text = 'Cancel Test';
            app.Dynamic_Mode_Button = uibutton(app.DynamicTab, 'push');
            app.Dynamic_Mode_Button.ButtonPushedFcn = createCallbackFcn(app, @Dynamic_Mode_ButtonPushed, true);
            app.Dynamic_Mode_Button.Text = 'Prepare Dynamic Test';
            app.Dynamic_Begin_Button = uibutton(app.DynamicTab, 'push');
            app.Dynamic_Begin_Button.ButtonPushedFcn = createCallbackFcn(app, @Dynamic_Begin_ButtonPushed, true);
            app.Dynamic_Begin_Button.Text = 'Begin Test';
            app.TimeofTesttEditFieldLabel = uilabel(app.DynamicTab);
            app.TimeofTesttEditFieldLabel.Text = 'Time of Test (t)';
            app.Dynamic_Duration = uieditfield(app.DynamicTab, 'numeric');
            app.dynamicdemoButton = uibutton(app.DynamicTab, 'push');
            app.dynamicdemoButton.ButtonPushedFcn = createCallbackFcn(app, @Dynamic_Demo_ButtonPushed, true);
            app.dynamicdemoButton.Text = 'dynamic demo';
            app.BeginXTestButton = uibutton(app.DynamicTab, 'push');
            app.BeginXTestButton.ButtonPushedFcn = createCallbackFcn(app, @BeginXTestButtonPushed, true);
            app.BeginXTestButton.Text = 'Begin X Test';
            app.dynamicdemo2Button = uibutton(app.DynamicTab, 'push');
            app.dynamicdemo2Button.ButtonPushedFcn = createCallbackFcn(app, @Dynamic_Demo2_ButtonPushed, true);
            app.dynamicdemo2Button.Text = 'dynamic demo2';
            app.BeginYTestButton = uibutton(app.DynamicTab, 'push');
            app.BeginYTestButton.ButtonPushedFcn = createCallbackFcn(app, @BeginYTestButtonPushed, true);
            app.BeginYTestButton.Text = 'Begin Y Test';
            app.BeginZTestButton = uibutton(app.DynamicTab, 'push');
            app.BeginZTestButton.ButtonPushedFcn = createCallbackFcn(app, @BeginZTestButtonPushed, true);
            app.BeginZTestButton.Text = 'Begin Z Test';
            app.BeginRollTestButton = uibutton(app.DynamicTab, 'push');
            app.BeginRollTestButton.ButtonPushedFcn = createCallbackFcn(app, @BeginRollTestButtonPushed, true);
            app.BeginRollTestButton.Text = 'Begin Roll Test';
            app.BeginPitchTestButton = uibutton(app.DynamicTab, 'push');
            app.BeginPitchTestButton.ButtonPushedFcn = createCallbackFcn(app, @BeginPitchTestButtonPushed, true);
            app.BeginPitchTestButton.Text = 'Begin Pitch Test';
            app.BeginYawTestButton = uibutton(app.DynamicTab, 'push');
            app.BeginYawTestButton.ButtonPushedFcn = createCallbackFcn(app, @BeginYawTestButtonPushed, true);
            app.BeginYawTestButton.Text = 'Begin Yaw Test';
            app.WaypointTab = uitab(app.TabGroup);
            app.WaypointTab.Title = 'Waypoint';
            app.SelectFileButton = uibutton(app.WaypointTab, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.Waypoint_Cancel_Button = uibutton(app.WaypointTab, 'push');
            app.Waypoint_Cancel_Button.ButtonPushedFcn = createCallbackFcn(app, @Waypoint_Cancel_ButtonPushed, true);
            app.Waypoint_Cancel_Button.Text = 'Cancel Test';
            app.Waypoint_Mode_Button = uibutton(app.WaypointTab, 'push');
            app.Waypoint_Mode_Button.ButtonPushedFcn = createCallbackFcn(app, @Waypoint_Mode_ButtonPushed, true);
            app.Waypoint_Mode_Button.Text = 'Prepare Waypoint Test';
            app.Waypoint_Begin_Button = uibutton(app.WaypointTab, 'push');
            app.Waypoint_Begin_Button.ButtonPushedFcn = createCallbackFcn(app, @Waypoint_Begin_ButtonPushed, true);
            app.Waypoint_Begin_Button.Text = 'Begin Test';
            app.TextArea_5 = uitextarea(app.WaypointTab);
            app.TextArea_5.Value = {'Please Select a .csv file which contains self-defined waypoints'};
            app.TestMotorTab = uitab(app.TabGroup);
            app.TestMotorTab.Title = 'Test Motor';
            app.Label = uilabel(app.TestMotorTab);
            app.Label.Text = '0';
            app.motor0 = uislider(app.TestMotorTab);
            app.motor0.Limits = [0 60000];
            app.motor0.ValueChangedFcn = createCallbackFcn(app, @motor0ValueChanged, true);
            app.Label_2 = uilabel(app.TestMotorTab);
            app.Label_2.Text = '1';
            app.motor1 = uislider(app.TestMotorTab);
            app.motor1.Limits = [0 60000];
            app.motor1.ValueChangedFcn = createCallbackFcn(app, @motor1ValueChanged, true);
            app.Label_3 = uilabel(app.TestMotorTab);
            app.Label_3.Text = '2';
            app.motor2 = uislider(app.TestMotorTab);
            app.motor2.Limits = [0 60000];
            app.motor2.ValueChangedFcn = createCallbackFcn(app, @motor2ValueChanged, true);
            app.Label_4 = uilabel(app.TestMotorTab);
            app.Label_4.Text = '3';
            app.motor3 = uislider(app.TestMotorTab);
            app.motor3.Limits = [0 60000];
            app.motor3.ValueChangedFcn = createCallbackFcn(app, @motor3ValueChanged, true);
            app.Label_5 = uilabel(app.TestMotorTab);
            app.Label_5.Text = '4';
            app.motor4 = uislider(app.TestMotorTab);
            app.motor4.Limits = [0 60000];
            app.motor4.ValueChangedFcn = createCallbackFcn(app, @motor4ValueChanged, true);
            app.Label_6 = uilabel(app.TestMotorTab);
            app.Label_6.Text = '5';
            app.motor5 = uislider(app.TestMotorTab);
            app.motor5.Limits = [0 60000];
            app.motor5.ValueChangedFcn = createCallbackFcn(app, @motor5ValueChanged, true);
            app.motor0_editField = uieditfield(app.TestMotorTab, 'numeric');
            app.motor0_editField.ValueDisplayFormat = '%6.5g';
            app.motor1_editField = uieditfield(app.TestMotorTab, 'numeric');
            app.motor1_editField.ValueDisplayFormat = '%6.5g';
            app.motor2_editField = uieditfield(app.TestMotorTab, 'numeric');
            app.motor2_editField.ValueDisplayFormat = '%6.5g';
            app.motor3_editField = uieditfield(app.TestMotorTab, 'numeric');
            app.motor3_editField.ValueDisplayFormat = '%6.5g';
            app.motor4_editField = uieditfield(app.TestMotorTab, 'numeric');
            app.motor4_editField.ValueDisplayFormat = '%6.5g';
            app.motor5_editField = uieditfield(app.TestMotorTab, 'numeric');
            app.motor5_editField.ValueDisplayFormat = '%6.5g';
            app.Motor_Mode_Button = uibutton(app.TestMotorTab, 'push');
            app.Motor_Mode_Button.ButtonPushedFcn = createCallbackFcn(app, @Motor_Mode_ButtonPushed, true);
            app.Motor_Mode_Button.Text = 'Prepare Motor Test';
            app.Motor_Begin_Button = uibutton(app.TestMotorTab, 'push');
            app.Motor_Begin_Button.ButtonPushedFcn = createCallbackFcn(app, @Motor_Begin_ButtonPushed, true);
            app.Motor_Begin_Button.Text = 'Begin Test';
            app.Motor_Cancel_Button = uibutton(app.TestMotorTab, 'push');
            app.Motor_Cancel_Button.ButtonPushedFcn = createCallbackFcn(app, @Motor_Cancel_ButtonPushed, true);
            app.Motor_Cancel_Button.Text = 'Cancel Test';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Global_Begin_Test = uibutton(app.UIFigure, 'push');
            app.Global_Begin_Test.ButtonPushedFcn = createCallbackFcn(app, @Global_Begin_TestPushed, true);
            app.Global_Begin_Test.Text = 'Start Testing';
            app.EndTestingButton = uibutton(app.UIFigure, 'push');
            app.EndTestingButton.ButtonPushedFcn = createCallbackFcn(app, @EndTestingButtonPushed, true);
            app.EndTestingButton.Text = 'End Testing';
            app.CurrentStatusLabel = uilabel(app.UIFigure);
            app.CurrentStatusLabel.Text = {'Current Status:'; ''};
            app.Current_Status = uilabel(app.UIFigure);
            app.Current_Status.Text = 'label';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Leg 1'; 'Leg 2'; 'Leg 3'; 'Leg 4'; 'Leg 5'; 'Leg 6'};
            app.UITable.RowName = {''};
            app.UITable.ColumnEditable = false;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = merlet_ui_v3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end